classdef Formular_PL_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ADDRESSEESEditFieldLabel        matlab.ui.control.Label
        ADDRESSEESEditField             matlab.ui.control.EditField
        FILINGTIMEEditFieldLabel        matlab.ui.control.Label
        FILINGTIMEEditField             matlab.ui.control.EditField
        ORIGINATOREditFieldLabel        matlab.ui.control.Label
        ORIGINATOREditField             matlab.ui.control.EditField
        TYPEOFAIRCRAFTEditFieldLabel    matlab.ui.control.Label
        TYPEOFAIRCRAFTEditField         matlab.ui.control.EditField
        NUMBERLabel                     matlab.ui.control.Label
        NUMBEREditField                 matlab.ui.control.EditField
        EQUIPMENTEditFieldLabel         matlab.ui.control.Label
        EQUIPMENTEditField              matlab.ui.control.EditField
        DEPARTUREAERODROMEEditFieldLabel  matlab.ui.control.Label
        DEPARTUREAERODROMEEditField     matlab.ui.control.EditField
        TIMEhhmmEditFieldLabel          matlab.ui.control.Label
        TIMEhhmmEditField               matlab.ui.control.EditField
        CRUISINGSPEEDLabel              matlab.ui.control.Label
        CRUISINGSPEEDEditField          matlab.ui.control.EditField
        LEVELEditFieldLabel             matlab.ui.control.Label
        LEVELEditField                  matlab.ui.control.EditField
        ROUTELabel                      matlab.ui.control.Label
        ROUTEEditField                  matlab.ui.control.EditField
        DESTINATIONAERODROMELabel       matlab.ui.control.Label
        DESTINATIONAERODROMEEditField   matlab.ui.control.EditField
        TYPEOFFLIGHTDropDownLabel       matlab.ui.control.Label
        TYPEOFFLIGHTDropDown            matlab.ui.control.DropDown
        FLIGHTRULESDropDownLabel        matlab.ui.control.Label
        FLIGHTRULESDropDown             matlab.ui.control.DropDown
        AIRCRAFTIDENTYFICATIONEditField_2Label  matlab.ui.control.Label
        AIRCRAFTIDENTYFICATIONEditField_2  matlab.ui.control.EditField
        WAKETURBULENCECATDropDownLabel  matlab.ui.control.Label
        WAKETURBULENCECATDropDown       matlab.ui.control.DropDown
        SelecteqButton                  matlab.ui.control.Button
        OpenrouteplanningtoolButton     matlab.ui.control.Button
        TOTALEETEditFieldLabel          matlab.ui.control.Label
        TOTALEETEditField               matlab.ui.control.EditField
        ALTNAERODROMEEditFieldLabel     matlab.ui.control.Label
        ALTNAERODROMEEditField          matlab.ui.control.EditField
        NDALTNAERODROMEEditFieldLabel   matlab.ui.control.Label
        NDALTNAERODROMEEditField        matlab.ui.control.EditField
        OTHERINFORMATIONEditFieldLabel  matlab.ui.control.Label
        OTHERINFORMATIONEditField       matlab.ui.control.EditField
        OKButton                        matlab.ui.control.Button
        GenerateICAOformatFLmessegeLabel  matlab.ui.control.Label
        EEditFieldLabel                 matlab.ui.control.Label
        EnduranceEditField              matlab.ui.control.EditField
        ADDITIONALINFORMATIONLabel      matlab.ui.control.Label
        ENDURANCEhhmmLabel              matlab.ui.control.Label
        PERSONSONBOARDLabel             matlab.ui.control.Label
        PEditFieldLabel                 matlab.ui.control.Label
        PersonsOnBoardEditField         matlab.ui.control.EditField
        UCheckBox                       matlab.ui.control.CheckBox
        RLabel                          matlab.ui.control.Label
        VCheckBox                       matlab.ui.control.CheckBox
        ECheckBox                       matlab.ui.control.CheckBox
        EMERGENCYRADIOUHFVHFELTLabel    matlab.ui.control.Label
        PCheckBox                       matlab.ui.control.CheckBox
        SLabel                          matlab.ui.control.Label
        DCheckBox                       matlab.ui.control.CheckBox
        MCheckBox                       matlab.ui.control.CheckBox
        JCheckBox                       matlab.ui.control.CheckBox
        POLARLabel                      matlab.ui.control.Label
        DESERTLabel                     matlab.ui.control.Label
        MARITIMELabel                   matlab.ui.control.Label
        JUNGLELabel                     matlab.ui.control.Label
        LifeJacketsLCheckBox            matlab.ui.control.CheckBox
        JLabel                          matlab.ui.control.Label
        LifeJacketsFCheckBox            matlab.ui.control.CheckBox
        LifeJacketsUCheckBox            matlab.ui.control.CheckBox
        LifeJacketsVCheckBox            matlab.ui.control.CheckBox
        LIGHTLabel                      matlab.ui.control.Label
        FLUORESLabel                    matlab.ui.control.Label
        UHFLabel                        matlab.ui.control.Label
        VHFLabel                        matlab.ui.control.Label
        SURVIVALEQUIPMENTLabel          matlab.ui.control.Label
        DLabel                          matlab.ui.control.Label
        DINGHIESLabel                   matlab.ui.control.Label
        NUMBERLabel_2                   matlab.ui.control.Label
        CAPACITYLabel                   matlab.ui.control.Label
        DINGHIESNUMBER                  matlab.ui.control.EditField
        DINGHIESCAPACITY                matlab.ui.control.EditField
        COVERLabel                      matlab.ui.control.Label
        COLOURLabel                     matlab.ui.control.Label
        COLOUR                          matlab.ui.control.EditField
        ALabel                          matlab.ui.control.Label
        COLOURANDMARKINGS               matlab.ui.control.EditField
        AIRCRAFTCOLOURANDMARKINGSLabel  matlab.ui.control.Label
        NLabel                          matlab.ui.control.Label
        REMARKSEditField                matlab.ui.control.EditField
        REMARKSLabel                    matlab.ui.control.Label
        CLabel_2                        matlab.ui.control.Label
        PilotInCommand                  matlab.ui.control.EditField
        PILOTINCOMMANDLabel             matlab.ui.control.Label
        DinghiesCoverCheckBox           matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        DialogApp; % Description
        RoutePlanningApp;
        messegeFileName = "ICAO_FPLmessege.txt";
        
        SurvEqP ="";
        SurvEqD = "";
        SurvEqM = "";
        SurvEqJ = "";
        SurvEq="";
        
        LifeJacketsL ="";
        LifeJacketsF ="";
        LifeJacketsU = "";
        LifeJacketsV = "";
        LifeJackets="";
        EmrgencyRadioU="";
        EmrgencyRadioV="";
        EmrgencyRadioE="";
        EmrgencyRadio="";
        
        DinghiesCover=""
    end


    methods (Access = public)
        
        function passData(app,EquipmentAndSurveillence)
            app.EQUIPMENTEditField.Value = EquipmentAndSurveillence;
        end

        function routePlanning(app,Route, DepartureAerodrome,CruisingSpeed,FlightLevel,TotalEET,DestinationAerodrome)
         
        app.ROUTEEditField.Value = Route;
        app.DEPARTUREAERODROMEEditField.Value = DepartureAerodrome;
        app.CRUISINGSPEEDEditField.Value = CruisingSpeed;
        app.LEVELEditField.Value = FlightLevel;
        app.TOTALEETEditField.Value = TotalEET;
        app.DESTINATIONAERODROMEEditField.Value = DestinationAerodrome;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelecteqButton
        function SelecteqButtonPushed(app, event)
            %app.SelecteqButton.Enable = 'off';
             app.DialogApp = equipment(app);
             
           
        end

        % Button pushed function: OpenrouteplanningtoolButton
        function OpenrouteplanningtoolButtonPushed(app, event)
            app.RoutePlanningApp = app_Plan_Lotu3(app);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
        if isempty(app.REMARKSEditField.Value)
            Remarks ="";
        else
            Remarks = "N/"+app.REMARKS.Value;
        end           
FPLmessege = "(FPL-" + app.AIRCRAFTIDENTYFICATIONEditField_2.Value + "-"+ app.FLIGHTRULESDropDown.Value+app.TYPEOFFLIGHTDropDown.Value +newline...
         +"-"+app.TYPEOFAIRCRAFTEditField.Value+"/"+string(app.WAKETURBULENCECATDropDown.Value)+"-"+app.EQUIPMENTEditField.Value+newline...
         +"-"+app.DEPARTUREAERODROMEEditField.Value+app.TIMEhhmmEditField.Value+newline...
         +"-"+app.CRUISINGSPEEDEditField.Value+app.LEVELEditField.Value+" "+app.ROUTEEditField.Value +newline...
         +"-"+app.DESTINATIONAERODROMEEditField.Value+app.TOTALEETEditField.Value+" "+app.ALTNAERODROMEEditField.Value+" "+app.NDALTNAERODROMEEditField.Value+newline ...
         +"-"+app.OTHERINFORMATIONEditField.Value...
         +" "+app.SurvEq+" P/"+app.PersonsOnBoardEditField.Value+" A/"+app.COLOURANDMARKINGS.Value+" "+app.LifeJackets+" "+app.EmrgencyRadio+app.DINGHIESNUMBER.Value+app.DINGHIESCAPACITY.Value+app.DinghiesCover+app.COLOUR.Value...
         +Remarks+ " C/" + app.PilotInCommand.Value + " E/"+app.EnduranceEditField.Value+")";
     
      %fileID= fopen('D:\Praca_Magisterska_robota\Aplikacja_Plan_Lotu3\ICAO_FPLmessege.txt','w');
      fileID= fopen("ICAO_FPLmessege.txt",'w');
        fprintf(fileID,'%s',FPLmessege);
        end

        % Value changed function: PCheckBox
        function PCheckBoxValueChanged(app, event)
           if app.PCheckBox.Value
              app.SurvEqP="P";
           else
               app.SurvEqP ="";
           end
            app.SurvEq = "S/"+app.SurvEqP+app.SurvEqD+app.SurvEqM+app.SurvEqJ;
           if  app.PCheckBox.Value==0 && app.DCheckBox.Value==0 && app.MCheckBox.Value==0 && app.JCheckBox.Value==0 
               app.SurvEq ="";
           end
        end

        % Value changed function: DCheckBox
        function DCheckBoxValueChanged(app, event)
           if app.DCheckBox.Value
              app.SurvEqD="D";
           else
               app.SurvEqD ="";
           end
            app.SurvEq = "S/"+app.SurvEqP+app.SurvEqD+app.SurvEqM+app.SurvEqJ;
           if  app.PCheckBox.Value==0 && app.DCheckBox.Value==0 && app.MCheckBox.Value==0 && app.JCheckBox.Value==0 
               app.SurvEq ="";
           end
        end

        % Value changed function: MCheckBox
        function MCheckBoxValueChanged(app, event)
        if app.MCheckBox.Value
              app.SurvEqM="M";
           else
               app.SurvEqM ="";
           end
            app.SurvEq = "S/"+app.SurvEqP+app.SurvEqD+app.SurvEqM+app.SurvEqJ;
           if  app.PCheckBox.Value==0 && app.DCheckBox.Value==0 && app.MCheckBox.Value==0 && app.JCheckBox.Value==0 
               app.SurvEq ="";
           end 
        end

        % Value changed function: JCheckBox
        function JCheckBoxValueChanged(app, event)
           if app.JCheckBox.Value
              app.SurvEqJ="J";
           else
               app.SurvEqJ ="";
           end
            app.SurvEq = "S/"+app.SurvEqP+app.SurvEqD+app.SurvEqM+app.SurvEqJ;
           if  app.PCheckBox.Value==0 && app.DCheckBox.Value==0 && app.MCheckBox.Value==0 && app.JCheckBox.Value==0 
               app.SurvEq ="";
           end 
        end

        % Value changed function: UCheckBox
        function UCheckBoxValueChanged(app, event)
           if app.UCheckBox.Value
              app.EmrgencyRadioU="U";
           else
               app.EmrgencyRadioU ="";
           end
            app.EmrgencyRadio = "R/"+app.EmrgencyRadioU+app.EmrgencyRadioV+app.EmrgencyRadioE;
           if  app.UCheckBox.Value==0 && app.VCheckBox.Value==0 && app.ECheckBox.Value==0
               app.EmrgencyRadio="";
           end
        end

        % Value changed function: VCheckBox
        function VCheckBoxValueChanged(app, event)
            if app.VCheckBox.Value
              app.EmrgencyRadioV="V";
           else
               app.EmrgencyRadioV ="";
           end
            app.EmrgencyRadio = "R/"+app.EmrgencyRadioU+app.EmrgencyRadioV+app.EmrgencyRadioE;
            if  app.UCheckBox.Value==0 && app.VCheckBox.Value==0 && app.ECheckBox.Value==0
               app.EmrgencyRadio="";
           end
            
        end

        % Value changed function: ECheckBox
        function ECheckBoxValueChanged(app, event)
            if app.ECheckBox.Value
              app.EmrgencyRadioE="E";
           else
               app.EmrgencyRadioE ="";
           end
            app.EmrgencyRadio = "R/"+app.EmrgencyRadioU+app.EmrgencyRadioV+app.EmrgencyRadioE;
           if  app.UCheckBox.Value==0 && app.VCheckBox.Value==0 && app.ECheckBox.Value==0
               app.EmrgencyRadio="";
           end 
            
        end

        % Value changed function: LifeJacketsLCheckBox
        function LifeJacketsLCheckBoxValueChanged(app, event)
            if app.LifeJacketsLCheckBox.Value
              app.LifeJacketsL="L";
           else
               app.LifeJacketsL ="";
           end
            app.LifeJackets = "J/"+app.LifeJacketsL+app.LifeJacketsF+app.LifeJacketsU+app.LifeJacketsV;
           if  app.LifeJacketsLCheckBox.Value==0 && app.LifeJacketsFCheckBox.Value==0 && app.LifeJacketsUCheckBox.Value==0 && app.LifeJacketsVLCheckBox.Value==0
            app.LifeJackets = "";
           end
        end

        % Value changed function: LifeJacketsFCheckBox
        function LifeJacketsFCheckBoxValueChanged(app, event)
           if app.LifeJacketsFCheckBox.Value
              app.LifeJacketsF="F";
           else
               app.LifeJacketsF ="";
           end
            app.LifeJackets = "J/"+app.LifeJacketsL+app.LifeJacketsF+app.LifeJacketsU+app.LifeJacketsV;
           if  app.LifeJacketsLCheckBox.Value==0 && app.LifeJacketsFCheckBox.Value==0 && app.LifeJacketsUCheckBox.Value==0 && app.LifeJacketsVLCheckBox.Value==0
            app.LifeJackets = "";
           end
        end

        % Value changed function: LifeJacketsUCheckBox
        function LifeJacketsUCheckBoxValueChanged(app, event)
            if app.LifeJacketsUCheckBox.Value
              app.LifeJacketsU="U";
           else
               app.LifeJacketsU ="";
           end
            app.LifeJackets = "J/"+app.LifeJacketsL+app.LifeJacketsF+app.LifeJacketsU+app.LifeJacketsV;
           if  app.LifeJacketsLCheckBox.Value==0 && app.LifeJacketsFCheckBox.Value==0 && app.LifeJacketsUCheckBox.Value==0 && app.LifeJacketsVLCheckBox.Value==0
            app.LifeJackets = "";
           end
        end

        % Value changed function: LifeJacketsVCheckBox
        function LifeJacketsVCheckBoxValueChanged(app, event)
            if app.LifeJacketsVCheckBox.Value
              app.LifeJacketsV="V";
           else
               app.LifeJacketsV ="";
           end
            app.LifeJackets = "J/"+app.LifeJacketsL+app.LifeJacketsF+app.LifeJacketsU+app.LifeJacketsV;
           if  app.LifeJacketsLCheckBox.Value==0 && app.LifeJacketsFCheckBox.Value==0 && app.LifeJacketsUCheckBox.Value==0 && app.LifeJacketsVLCheckBox.Value==0
            app.LifeJackets = "";
           end
        end

        % Value changed function: DinghiesCoverCheckBox
        function DinghiesCoverCheckBoxValueChanged(app, event)
            if app.DinghiesCoverCheckBox.Value
              app.DinghiesCover="C";
           else
               app.DinghiesCover ="";
           end
            
        end

        % Value changed function: TOTALEETEditField
        function TOTALEETEditFieldValueChanged(app, event)
            if str2double(app.TOTALEETEditField.Value) > str2double(app.EnduranceEditField.Value)
            app.TOTALEETEditField.BackgroundColor = "red";
            app.EnduranceEditField.BackgroundColor = "red";
            app.OKButton.Enable = "off";
            else
            app.TOTALEETEditField.BackgroundColor = "white";
            app.EnduranceEditField.BackgroundColor = "white";
            app.OKButton.Enable = "on";
            end
        end

        % Value changed function: EnduranceEditField
        function EnduranceEditFieldValueChanged(app, event)
            if str2double(app.TOTALEETEditField.Value) > str2double(app.EnduranceEditField.Value)
            app.TOTALEETEditField.BackgroundColor = "red";
            app.EnduranceEditField.BackgroundColor = "red";
            app.OKButton.Enable = "off";
            else
            app.TOTALEETEditField.BackgroundColor = "white";
            app.EnduranceEditField.BackgroundColor = "white";
            app.OKButton.Enable = "on";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ADDRESSEESEditFieldLabel = uilabel(app.UIFigure);
            app.ADDRESSEESEditFieldLabel.Text = 'ADDRESSEE(S)';
            app.ADDRESSEESEditField = uieditfield(app.UIFigure, 'text');
            app.FILINGTIMEEditFieldLabel = uilabel(app.UIFigure);
            app.FILINGTIMEEditFieldLabel.Text = 'FILING TIME';
            app.FILINGTIMEEditField = uieditfield(app.UIFigure, 'text');
            app.ORIGINATOREditFieldLabel = uilabel(app.UIFigure);
            app.ORIGINATOREditFieldLabel.Text = 'ORIGINATOR';
            app.ORIGINATOREditField = uieditfield(app.UIFigure, 'text');
            app.TYPEOFAIRCRAFTEditFieldLabel = uilabel(app.UIFigure);
            app.TYPEOFAIRCRAFTEditFieldLabel.Text = 'TYPE OF AIRCRAFT';
            app.TYPEOFAIRCRAFTEditField = uieditfield(app.UIFigure, 'text');
            app.NUMBERLabel = uilabel(app.UIFigure);
            app.NUMBERLabel.Text = '9  NUMBER';
            app.NUMBEREditField = uieditfield(app.UIFigure, 'text');
            app.EQUIPMENTEditFieldLabel = uilabel(app.UIFigure);
            app.EQUIPMENTEditFieldLabel.Text = '10  EQUIPMENT';
            app.EQUIPMENTEditField = uieditfield(app.UIFigure, 'text');
            app.DEPARTUREAERODROMEEditFieldLabel = uilabel(app.UIFigure);
            app.DEPARTUREAERODROMEEditFieldLabel.Text = '13  DEPARTURE AERODROME';
            app.DEPARTUREAERODROMEEditField = uieditfield(app.UIFigure, 'text');
            app.TIMEhhmmEditFieldLabel = uilabel(app.UIFigure);
            app.TIMEhhmmEditFieldLabel.Text = 'TIME hhmm';
            app.TIMEhhmmEditField = uieditfield(app.UIFigure, 'text');
            app.CRUISINGSPEEDLabel = uilabel(app.UIFigure);
            app.CRUISINGSPEEDLabel.Text = '15 CRUISING SPEED';
            app.CRUISINGSPEEDEditField = uieditfield(app.UIFigure, 'text');
            app.LEVELEditFieldLabel = uilabel(app.UIFigure);
            app.LEVELEditFieldLabel.Text = 'LEVEL';
            app.LEVELEditField = uieditfield(app.UIFigure, 'text');
            app.ROUTELabel = uilabel(app.UIFigure);
            app.ROUTELabel.Text = 'ROUTE';
            app.ROUTEEditField = uieditfield(app.UIFigure, 'text');
            app.DESTINATIONAERODROMELabel = uilabel(app.UIFigure);
            app.DESTINATIONAERODROMELabel.Text = '16  DESTINATION AERODROME';
            app.DESTINATIONAERODROMEEditField = uieditfield(app.UIFigure, 'text');
            app.TYPEOFFLIGHTDropDownLabel = uilabel(app.UIFigure);
            app.TYPEOFFLIGHTDropDownLabel.Text = ' TYPE OF FLIGHT';
            app.TYPEOFFLIGHTDropDown = uidropdown(app.UIFigure);
            app.TYPEOFFLIGHTDropDown.Items = {'S', 'N', 'G', 'M', 'X'};
            app.TYPEOFFLIGHTDropDown.Value = 'S';
            app.FLIGHTRULESDropDownLabel = uilabel(app.UIFigure);
            app.FLIGHTRULESDropDownLabel.Text = 'FLIGHT RULES';
            app.FLIGHTRULESDropDown = uidropdown(app.UIFigure);
            app.FLIGHTRULESDropDown.Items = {'I', 'V', 'Y', 'Z'};
            app.FLIGHTRULESDropDown.Value = 'I';
            app.AIRCRAFTIDENTYFICATIONEditField_2Label = uilabel(app.UIFigure);
            app.AIRCRAFTIDENTYFICATIONEditField_2Label.Text = '7. AIRCRAFT IDENTYFICATION';
            app.AIRCRAFTIDENTYFICATIONEditField_2 = uieditfield(app.UIFigure, 'text');
            app.WAKETURBULENCECATDropDownLabel = uilabel(app.UIFigure);
            app.WAKETURBULENCECATDropDownLabel.Text = 'WAKE TURBULENCE CAT';
            app.WAKETURBULENCECATDropDown = uidropdown(app.UIFigure);
            app.WAKETURBULENCECATDropDown.Items = {'L', 'M', 'H'};
            app.WAKETURBULENCECATDropDown.Value = 'L';
            app.SelecteqButton = uibutton(app.UIFigure, 'push');
            app.SelecteqButton.ButtonPushedFcn = createCallbackFcn(app, @SelecteqButtonPushed, true);
            app.SelecteqButton.Text = 'Select eq.';
            app.OpenrouteplanningtoolButton = uibutton(app.UIFigure, 'push');
            app.OpenrouteplanningtoolButton.ButtonPushedFcn = createCallbackFcn(app, @OpenrouteplanningtoolButtonPushed, true);
            app.OpenrouteplanningtoolButton.Text = {'Open route '; 'planning tool'};
            app.TOTALEETEditFieldLabel = uilabel(app.UIFigure);
            app.TOTALEETEditFieldLabel.Text = 'TOTAL EET';
            app.TOTALEETEditField = uieditfield(app.UIFigure, 'text');
            app.TOTALEETEditField.ValueChangedFcn = createCallbackFcn(app, @TOTALEETEditFieldValueChanged, true);
            app.ALTNAERODROMEEditFieldLabel = uilabel(app.UIFigure);
            app.ALTNAERODROMEEditFieldLabel.Text = 'ALTN AERODROME';
            app.ALTNAERODROMEEditField = uieditfield(app.UIFigure, 'text');
            app.NDALTNAERODROMEEditFieldLabel = uilabel(app.UIFigure);
            app.NDALTNAERODROMEEditFieldLabel.Text = '2ND ALTN AERODROME';
            app.NDALTNAERODROMEEditField = uieditfield(app.UIFigure, 'text');
            app.OTHERINFORMATIONEditFieldLabel = uilabel(app.UIFigure);
            app.OTHERINFORMATIONEditFieldLabel.Text = '18 OTHER INFORMATION';
            app.OTHERINFORMATIONEditField = uieditfield(app.UIFigure, 'text');
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.GenerateICAOformatFLmessegeLabel = uilabel(app.UIFigure);
            app.GenerateICAOformatFLmessegeLabel.Text = {'Generate ICAO '; 'format FL messege'};
            app.EEditFieldLabel = uilabel(app.UIFigure);
            app.EEditFieldLabel.Text = 'E/';
            app.EnduranceEditField = uieditfield(app.UIFigure, 'text');
            app.EnduranceEditField.ValueChangedFcn = createCallbackFcn(app, @EnduranceEditFieldValueChanged, true);
            app.ADDITIONALINFORMATIONLabel = uilabel(app.UIFigure);
            app.ADDITIONALINFORMATIONLabel.Text = '19 ADDITIONAL INFORMATION';
            app.ENDURANCEhhmmLabel = uilabel(app.UIFigure);
            app.ENDURANCEhhmmLabel.Text = 'ENDURANCE hhmm';
            app.PERSONSONBOARDLabel = uilabel(app.UIFigure);
            app.PERSONSONBOARDLabel.Text = 'PERSONS ON BOARD';
            app.PEditFieldLabel = uilabel(app.UIFigure);
            app.PEditFieldLabel.Text = 'P/';
            app.PersonsOnBoardEditField = uieditfield(app.UIFigure, 'text');
            app.UCheckBox = uicheckbox(app.UIFigure);
            app.UCheckBox.ValueChangedFcn = createCallbackFcn(app, @UCheckBoxValueChanged, true);
            app.UCheckBox.Text = 'U';
            app.RLabel = uilabel(app.UIFigure);
            app.RLabel.Text = 'R/';
            app.VCheckBox = uicheckbox(app.UIFigure);
            app.VCheckBox.ValueChangedFcn = createCallbackFcn(app, @VCheckBoxValueChanged, true);
            app.VCheckBox.Text = 'V';
            app.ECheckBox = uicheckbox(app.UIFigure);
            app.ECheckBox.ValueChangedFcn = createCallbackFcn(app, @ECheckBoxValueChanged, true);
            app.ECheckBox.Text = 'E';
            app.EMERGENCYRADIOUHFVHFELTLabel = uilabel(app.UIFigure);
            app.EMERGENCYRADIOUHFVHFELTLabel.Text = {'   EMERGENCY RADIO'; '           UHF   VHF   ELT'};
            app.PCheckBox = uicheckbox(app.UIFigure);
            app.PCheckBox.ValueChangedFcn = createCallbackFcn(app, @PCheckBoxValueChanged, true);
            app.PCheckBox.Text = 'P';
            app.SLabel = uilabel(app.UIFigure);
            app.SLabel.Text = 'S/';
            app.DCheckBox = uicheckbox(app.UIFigure);
            app.DCheckBox.ValueChangedFcn = createCallbackFcn(app, @DCheckBoxValueChanged, true);
            app.DCheckBox.Text = 'D';
            app.MCheckBox = uicheckbox(app.UIFigure);
            app.MCheckBox.ValueChangedFcn = createCallbackFcn(app, @MCheckBoxValueChanged, true);
            app.MCheckBox.Text = 'M';
            app.JCheckBox = uicheckbox(app.UIFigure);
            app.JCheckBox.ValueChangedFcn = createCallbackFcn(app, @JCheckBoxValueChanged, true);
            app.JCheckBox.Text = 'J';
            app.POLARLabel = uilabel(app.UIFigure);
            app.POLARLabel.Text = 'POLAR';
            app.DESERTLabel = uilabel(app.UIFigure);
            app.DESERTLabel.Text = 'DESERT';
            app.MARITIMELabel = uilabel(app.UIFigure);
            app.MARITIMELabel.Text = 'MARITIME';
            app.JUNGLELabel = uilabel(app.UIFigure);
            app.JUNGLELabel.Text = 'JUNGLE';
            app.LifeJacketsLCheckBox = uicheckbox(app.UIFigure);
            app.LifeJacketsLCheckBox.ValueChangedFcn = createCallbackFcn(app, @LifeJacketsLCheckBoxValueChanged, true);
            app.LifeJacketsLCheckBox.Text = 'L';
            app.JLabel = uilabel(app.UIFigure);
            app.JLabel.Text = 'J/';
            app.LifeJacketsFCheckBox = uicheckbox(app.UIFigure);
            app.LifeJacketsFCheckBox.ValueChangedFcn = createCallbackFcn(app, @LifeJacketsFCheckBoxValueChanged, true);
            app.LifeJacketsFCheckBox.Text = 'F';
            app.LifeJacketsUCheckBox = uicheckbox(app.UIFigure);
            app.LifeJacketsUCheckBox.ValueChangedFcn = createCallbackFcn(app, @LifeJacketsUCheckBoxValueChanged, true);
            app.LifeJacketsUCheckBox.Text = 'U';
            app.LifeJacketsVCheckBox = uicheckbox(app.UIFigure);
            app.LifeJacketsVCheckBox.ValueChangedFcn = createCallbackFcn(app, @LifeJacketsVCheckBoxValueChanged, true);
            app.LifeJacketsVCheckBox.Text = 'V';
            app.LIGHTLabel = uilabel(app.UIFigure);
            app.LIGHTLabel.Text = 'LIGHT';
            app.FLUORESLabel = uilabel(app.UIFigure);
            app.FLUORESLabel.Text = 'FLUORES';
            app.UHFLabel = uilabel(app.UIFigure);
            app.UHFLabel.Text = 'UHF';
            app.VHFLabel = uilabel(app.UIFigure);
            app.VHFLabel.Text = 'VHF';
            app.SURVIVALEQUIPMENTLabel = uilabel(app.UIFigure);
            app.SURVIVALEQUIPMENTLabel.Text = {'SURVIVAL'; 'EQUIPMENT'};
            app.DLabel = uilabel(app.UIFigure);
            app.DLabel.Text = 'D/';
            app.DINGHIESLabel = uilabel(app.UIFigure);
            app.DINGHIESLabel.Text = 'DINGHIES';
            app.NUMBERLabel_2 = uilabel(app.UIFigure);
            app.NUMBERLabel_2.Text = 'NUMBER';
            app.CAPACITYLabel = uilabel(app.UIFigure);
            app.CAPACITYLabel.Text = 'CAPACITY';
            app.DINGHIESNUMBER = uieditfield(app.UIFigure, 'text');
            app.DINGHIESCAPACITY = uieditfield(app.UIFigure, 'text');
            app.COVERLabel = uilabel(app.UIFigure);
            app.COVERLabel.Text = 'COVER';
            app.COLOURLabel = uilabel(app.UIFigure);
            app.COLOURLabel.Text = 'COLOUR';
            app.COLOUR = uieditfield(app.UIFigure, 'text');
            app.ALabel = uilabel(app.UIFigure);
            app.ALabel.Text = 'A/';
            app.COLOURANDMARKINGS = uieditfield(app.UIFigure, 'text');
            app.AIRCRAFTCOLOURANDMARKINGSLabel = uilabel(app.UIFigure);
            app.AIRCRAFTCOLOURANDMARKINGSLabel.Text = 'AIRCRAFT COLOUR AND MARKINGS';
            app.NLabel = uilabel(app.UIFigure);
            app.NLabel.Text = 'N/';
            app.REMARKSEditField = uieditfield(app.UIFigure, 'text');
            app.REMARKSLabel = uilabel(app.UIFigure);
            app.REMARKSLabel.Text = 'REMARKS';
            app.CLabel_2 = uilabel(app.UIFigure);
            app.CLabel_2.Text = 'C/';
            app.PilotInCommand = uieditfield(app.UIFigure, 'text');
            app.PILOTINCOMMANDLabel = uilabel(app.UIFigure);
            app.PILOTINCOMMANDLabel.Text = 'PILOT IN COMMAND';
            app.DinghiesCoverCheckBox = uicheckbox(app.UIFigure);
            app.DinghiesCoverCheckBox.ValueChangedFcn = createCallbackFcn(app, @DinghiesCoverCheckBoxValueChanged, true);
            app.DinghiesCoverCheckBox.Text = 'C';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Formular_PL_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end