classdef app_Plan_Lotu3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        LatEditFieldLabel              matlab.ui.control.Label
        LatEditField                   matlab.ui.control.NumericEditField
        LonEditFieldLabel              matlab.ui.control.Label
        LonEditField                   matlab.ui.control.NumericEditField
        UITable                        matlab.ui.control.Table
        AddpointButton                 matlab.ui.control.Button
        ZatwierdzDodaniePunktuButton   matlab.ui.control.Button
        UsunPunktButton                matlab.ui.control.Button
        NoLabel                        matlab.ui.control.Label
        LpPunktDoUsunieciaEditField    matlab.ui.control.NumericEditField
        ProfilTerenuZatwierdzButton    matlab.ui.control.Button
        GenerateterrainprofileLabel    matlab.ui.control.Label
        CruisingspeedkmhLabel          matlab.ui.control.Label
        PredkoscPrzelotowaEditField    matlab.ui.control.NumericEditField
        AltitudeFLLabel                matlab.ui.control.Label
        WysokoscPrzelotowaEditField    matlab.ui.control.NumericEditField
        TrasaEditField                 matlab.ui.control.EditField
        DepartureaerodromeLabel        matlab.ui.control.Label
        LotniskaStartoweListBox        matlab.ui.control.ListBox
        DepartureaerodromeLabel_2      matlab.ui.control.Label
        LotniskoStartoweEditField      matlab.ui.control.EditField
        DestinationaerodromeLabel      matlab.ui.control.Label
        LotniskaDoceloweListBox        matlab.ui.control.ListBox
        DestinationaerodromeLabel_2    matlab.ui.control.Label
        LotniskoDoceloweEditField      matlab.ui.control.EditField
        RouteButton                    matlab.ui.control.Button
        SummaryButton                  matlab.ui.control.Button
        EEThhmmEditFieldLabel          matlab.ui.control.Label
        EEThhmmEditField               matlab.ui.control.EditField
        TotaldistancekmEditFieldLabel  matlab.ui.control.Label
        TotaldistancekmEditField       matlab.ui.control.NumericEditField
        ConfirmButton                  matlab.ui.control.Button
    end


    properties (Access = public)
lat=0;  %do tej zmiennej przypisana jest wartosc z pola edycyjnego "Lat"
lon=0;  %do tej zmiennej przypisana jest wartosc z pola edycyjnego "Lon"
gx;     %później zadeklarowany jako obiekt klasy geoaxes czyli mapa
tablica_pomocnicza= [];
Lp = 0;
%wstępna deklaracja wektora przechowującego 
%współrzędne geograficzne pomocy nawigacyjnych            
rnav_lats =0;   
rnav_lons=0;
%stworzenie połączenia z baża danych       
conn = database('PostgreSQL30','postgres','z');
%cyfrowe dane o elewacji terenu Z - wysokosci poszeczegolnych komorek R - opcje dotyczace pliku
Z=[];
R;
%kolumny tabeli, pozwoli latwo zmieniac kolejnosc kolumn i odczytywać dane
LpKolumna = 1;
RNAVKolumna = 2;
LatKolumna = 3;
LonKolumna = 4;
OdlegloscKolumna = 5;
KursKolumna = 6;
PredkoscPrzelotowaKolumna =7;
CzasPrzelotuKolumna =8; %liczony między punktami
WysokoscKolumna =9;     %opoznienie przy danym miejscu
%Ostatni wiersz znacznie różni się od poprzednich
wiersz_docelowy = [ "-" "-" "-" "-" "-" "-" "-" "-" "-"]; 
%struktura danych przechowująca dane lotniskach
lotniskoStartowe = struct('kodicao','----','nazwalotniska','-','lat',50,'lon',50);
lotniskoDocelowe = struct('kodicao','----','nazwalotniska','-','lat',50,'lon',50);
%Przekazywanie danych pomiedzy aplikacjami
CallingApp;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, formularz, DepartureAerodrome, Route, CruisingSpeed, FlightLevel, DestinationAerodrome)
app.gx = geoaxes('Basemap',"topographic");  %Otworzenie okna z mapą Polski
        
query = "select (lat) from pomoce_r";       %Zapytanie do bazy danych
app.rnav_lats  = select(app.conn,query);    %Pobranie danych o szerokościach geog.
app.rnav_lats = table2array(app.rnav_lats); %Zmiana typu danych
query = "select (lon) from pomoce_r";       %Te same czynności lecz dla długości geog.
app.rnav_lons  = select(app.conn,query);
app.rnav_lons = table2array(app.rnav_lons);
%Wyświetlenie pomocy nawigacyjnych na mapie
hold on;
for i=1:1:length(app.rnav_lats)
 geoplot(app.gx,app.rnav_lats(i),app.rnav_lons(i),'Marker','.','Color','green');
end
geobasemap(app.gx,"topographic");         

%--cyfrowe dane o elewacji terenu Z - wysokosci poszeczegolnych komorek R - opcje dotyczace pliku
[app.Z,app.R] = readgeoraster('D:\Praca_Magisterska_robota\Aplikacja_Plan_Lotu2\50n000e_20101117_gmted_bln300.tif','OutputType','double');

%--lotnisko startowe wybrane domyślnie 
query="select * from lotniska where nazwalotniska ='"+app.LotniskaStartoweListBox.Value+"'";
app.lotniskoStartowe = select(app.conn,query);

query="select * from lotniska where nazwalotniska ='"+app.LotniskaDoceloweListBox.Value+"'";
app.lotniskoDocelowe = select(app.conn,query);

app.LotniskoStartoweEditField.Value = string(app.lotniskoStartowe.nazwalotniska);
app.LotniskoDoceloweEditField.Value = string(app.lotniskoDocelowe.nazwalotniska);
%Przekazywanie danych pomiedzy aplikacjami
app.CallingApp = formularz;
        end

        % Button pushed function: AddpointButton
        function AddpointButtonPushed(app, event)
            [app.lat, app.lon] = ginput(1);   %obsługa wybrania punktu z mapy, zwraa współrzędne geograficzne punktu
            app.LonEditField.Value = app.lat;
            app.LatEditField.Value = app.lon;     
        end

        % Button pushed function: ZatwierdzDodaniePunktuButton
        function ZatwierdzDodaniePunktuButtonPushed(app, event)
if app.PredkoscPrzelotowaEditField.Value ==0
   komunikat_predkoscprzelotowa;
   else
   %liczba porzadkowa, nr na trasie
   app.Lp = app.Lp + 1;
   %----obliczanie dystansu od poprzedniego punktu -------
   if app.Lp ==1
      odleglosc = distance(app.lotniskoStartowe.lat,app.lotniskoStartowe.lon,app.lat, app.lon);
      odleglosc = deg2km(odleglosc);
      else
      odleglosc = distance([str2double(app.tablica_pomocnicza((app.Lp-1),3)),...
                           str2double( app.tablica_pomocnicza((app.Lp-1),4))],[app.lat, app.lon]);
      odleglosc = deg2km(odleglosc);
   end
%---dodanie lub nie, nazwy znaczącego punktu radionawigacyjnego
%   kod wybiera punkt umieszczony najbliżej wybranych współrzędnych
%pomoc nawigacyjna zostanie dodana jeżeli będzie się znajdowała w odpowiednim
% zakresie współrzędnych, czyli dostatecznie blisko wybranego z mapy miejsca
zakres = 0.1;

lat_zakres_dolny = app.lat - zakres;
lat_zakres_gorny = app.lat + zakres;

lon_zakres_dolny = app.lon - zakres;
lon_zakres_gorny = app.lon + zakres;
%pobranie z bazy danych punktów które znajdują się w pobliżu
query = "select * from pomoce_r where lat >="+lat_zakres_dolny+" AND lat <=" + lat_zakres_gorny+ ...
    " AND lon>="+lon_zakres_dolny+" AND lon <= "+lon_zakres_gorny;
data  = select(app.conn,query);
%Obsługa klikniecia myszką na mapę. Klikniecie lewym przyciskiem myszy w odpowiedniej 
%odleglosci od pomocy radionaw. spowoduje uwzględnienie jej w tabelce
stop = size(data);  %pętla for wykona się tyle razy ile w pobliżu jest punktów sprawdzając który punkt jest najbliżej
if stop(1)>1
 najblizej = distance(app.LatEditField.Value, data{1,3},data{1,2}, app.LonEditField.Value);  
punkt = 1;
 for i=1:1:stop(1)
   najblizej2 =  distance(app.LatEditField.Value, data{i,3},data{i,2}, app.LonEditField.Value);
   if najblizej2 < najblizej
       najblizej = najblizej2;
       punkt = i;
   end 
end
    RNAV = data{punkt,1};

elseif stop(1)==1
    RNAV = data{1,1};
    
elseif stop(1)==0
    RNAV ="-";    
end
RNAV=[RNAV{:}];
RNAV=string(RNAV);
%---koniec obslugi kliknięcia na mapę

%wyznaczenie kursu
if app.Lp ==1
    kurs = azimuth(app.lotniskoStartowe.lat, ...
                   app.lotniskoStartowe.lon, ...
                                    app.lat, ...
                                    app.lon ) ;
else
    kurs = azimuth(str2double(app.UITable.Data(app.Lp-1,app.LatKolumna)), ...
                   str2double(app.UITable.Data(app.Lp-1,app.LonKolumna)), ...
                   app.lat, ...
                   app.lon ) ;
end
%---koniec wyznacznaia kursu

% ---dodawanie nowych wsp geograficznych
nowy_wiersz = [ "-" "-" "-" "-" "-" "-" "-" "-" "-"];
            
nowy_wiersz(1,app.LpKolumna)= num2str(app.Lp);
nowy_wiersz(1,app.RNAVKolumna)=RNAV;
nowy_wiersz(1,app.LatKolumna)=num2str(app.lat);
nowy_wiersz(1,app.LonKolumna)=num2str(app.lon);
nowy_wiersz(1,app.OdlegloscKolumna)=num2str(odleglosc); %policzony powyzej
nowy_wiersz(1,app.KursKolumna)=num2str(kurs); %policzony powyzej
nowy_wiersz(1,app.PredkoscPrzelotowaKolumna)=num2str(app.PredkoscPrzelotowaEditField.Value);
nowy_wiersz(1,app.WysokoscKolumna) = num2str(app.WysokoscPrzelotowaEditField.Value);
            
            if app.Lp == 1
                czas = datestr(hours(odleglosc/app.PredkoscPrzelotowaEditField.Value),'HH:MM');
            else
                czas = datestr(hours(odleglosc/app.PredkoscPrzelotowaEditField.Value),'HH:MM');
            end
            nowy_wiersz(1,app.CzasPrzelotuKolumna)=num2str(czas);
          
           app.tablica_pomocnicza = [app.tablica_pomocnicza; nowy_wiersz]; 
          
           %%--wiersz z lotniskiem docelowym (ostatni wiersz tabeli)
            app.wiersz_docelowy(app.LpKolumna) = string(app.Lp+1);
            app.wiersz_docelowy(app.RNAVKolumna) = string(app.lotniskoDocelowe.kodicao);              
            app.wiersz_docelowy(app.LatKolumna) = app.lotniskoDocelowe.lat;
            app.wiersz_docelowy(app.LonKolumna) = app.lotniskoDocelowe.lon;

            odleglosc = distance([str2double(app.tablica_pomocnicza((app.Lp),app.LatKolumna)),...
                        str2double( app.tablica_pomocnicza((app.Lp),app.LonKolumna))],...
                        [app.lotniskoDocelowe.lat, app.lotniskoDocelowe.lon]);
            odleglosc = deg2km(odleglosc);
            app.wiersz_docelowy(app.OdlegloscKolumna) =  odleglosc;
            app.wiersz_docelowy(app.KursKolumna) = azimuth(app.lotniskoDocelowe.lat, app.lotniskoDocelowe.lon,...
                                                    str2double(app.tablica_pomocnicza(app.Lp,app.LatKolumna)),...
                                                    str2double(app.tablica_pomocnicza(app.Lp,app.LonKolumna)));
            app.wiersz_docelowy(app.PredkoscPrzelotowaKolumna) = "lądowanie";

            czas = datestr(hours(odleglosc/app.PredkoscPrzelotowaEditField.Value),'HH:MM');
            app.wiersz_docelowy(app.CzasPrzelotuKolumna)= num2str(czas);                    
                       
            app.UITable.Data = [app.tablica_pomocnicza; app.wiersz_docelowy];
            %narysowanie trasy na mapie
            hold off;
            geoplot(app.gx, ...
                [app.lotniskoStartowe.lat; str2double(app.UITable.Data(:,app.LatKolumna))],...
                [app.lotniskoStartowe.lon; str2double(app.UITable.Data(:,app.LonKolumna))] ...
                );
            hold on;
            for i=1:1:length(app.rnav_lats)
            geoplot(app.gx,app.rnav_lats(i),app.rnav_lons(i),'Marker','.','Color','green');
            end
            geobasemap(app.gx,"topographic");
 end %if  
            
        end

        % Button pushed function: UsunPunktButton
        function UsunPunktButtonPushed(app, event)
            wiersz_usun = app.LpPunktDoUsunieciaEditField.Value;
            app.tablica_pomocnicza(wiersz_usun,:) = [];
            
            stop = size(app.tablica_pomocnicza);
            for i=1:1:stop(1)
                app.tablica_pomocnicza(i,1) = num2str(i);
            end
            
            S = size(app.tablica_pomocnicza);
            app.Lp = S(1);
            app.UITable.Data = app.tablica_pomocnicza;
            
            hold off;
            geoplot(app.gx,[str2double(app.tablica_pomocnicza(:,3))], [str2double(app.tablica_pomocnicza(:,4))] );
            hold on;
            for i=1:1:length(app.rnav_lats)
            geoplot(app.gx,app.rnav_lats(i),app.rnav_lons(i),'Marker','.','Color','green');
            end
       
            geobasemap(app.gx,"topographic");
        end

        % Button pushed function: ProfilTerenuZatwierdzButton
        function ProfilTerenuZatwierdzButtonPushed(app, event)
        [Zi,Ri,~,~] = mapprofile(app.Z,app.R,str2double(app.tablica_pomocnicza(:,3))',str2double(app.tablica_pomocnicza(:,4))');
        %os1=nexttile;
         Ri=deg2km(Ri);          
        
        figure('Name','elevation profile');
        plot(Ri,Zi);
        area(Ri,Zi);
        xlim([0 Ri(length(Ri))]);
        title('elevation profile');
        xlabel('distance [km]');
        ylabel('elevation [m]');        
        end

        % Value changed function: LotniskaStartoweListBox
        function LotniskaStartoweListBoxValueChanged(app, event)
        query="select * from lotniska where nazwalotniska ='"+app.LotniskaStartoweListBox.Value+"'";
        app.lotniskoStartowe = select(app.conn,query);
        app.LotniskoStartoweEditField.Value = string(app.lotniskoStartowe.nazwalotniska);
        end

        % Value changed function: LotniskaDoceloweListBox
        function LotniskaDoceloweListBoxValueChanged(app, event)
        query="select * from lotniska where nazwalotniska ='"+app.LotniskaDoceloweListBox.Value+"'";
        app.lotniskoDocelowe = select(app.conn,query);
        app.LotniskoDoceloweEditField.Value = string(app.lotniskoDocelowe.nazwalotniska);
        end

        % Button pushed function: RouteButton
        function RouteButtonPushed(app, event)
Trasa = "";
koniec = size(app.UITable.Data);
for i = 1:1:koniec(1)-1
    if app.UITable.Data(i,app.RNAVKolumna) == "-"
         punktTrasy = punktGeograficzny(str2double(app.UITable.Data(i,app.LatKolumna)),str2double(app.UITable.Data(i,app.LonKolumna)));
        else
        punktTrasy = app.UITable.Data(i,app.RNAVKolumna);
     end
                
       if i>1
         if app.UITable.Data(i,app.WysokoscKolumna) ~= app.UITable.Data(i-1,app.WysokoscKolumna)...                         %warunek
                            || app.UITable.Data(i,app.PredkoscPrzelotowaKolumna) ~= app.UITable.Data(i-1,app.PredkoscPrzelotowaKolumna)%warunek cd.
                        
          punktTrasy = zmianaParametrowLotu(app.PredkoscPrzelotowaEditField.Value,app.WysokoscPrzelotowaEditField.Value,punktTrasy);
          end
        end
              
        if i>1 && app.UITable.Data(i-1,app.RNAVKolumna) ~= "-" && app.UITable.Data(i,app.RNAVKolumna) ~= "-"
           Trasa = Trasa +" DCT "+ punktTrasy;
        elseif i == koniec(1)         
        else
           Trasa = Trasa +" "+ punktTrasy;
        end

end
app.TrasaEditField.Value = Trasa;
        end

        % Button pushed function: SummaryButton
        function SummaryButtonPushed(app, event)
            iloscWierszy = size(app.UITable.Data);
            sum = 0;
            for i=1:1:(iloscWierszy(1))
                czas = char(app.UITable.Data(i,app.CzasPrzelotuKolumna));
                minuty = string(czas(4))+string(czas(5));
                minuty = str2double(minuty);
                godziny = string(czas(1))+string(czas(2));
                godziny=str2double(godziny);
                czas2 = godziny*60 + minuty;
                czas2 = czas2/60;
                sum = sum + czas2;
            end
            app.EEThhmmEditField.Value=datestr(hours(sum),'HHMM');
            sum=0;
            for i=1:1:(iloscWierszy(1))
                dystans = str2double(app.UITable.Data(i,app.OdlegloscKolumna));
                sum = sum + dystans;
            end
            app.TotaldistancekmEditField.Value = sum;                
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
        %Przesłanie danych do formularza
            routePlanning(app.CallingApp,app.TrasaEditField.Value, ...
            string(app.lotniskoStartowe.kodicao), ...
            string("K"+app.UITable.Data(1,app.PredkoscPrzelotowaKolumna)), ...
            string(app.UITable.Data(1,app.WysokoscKolumna)), ...
            app.EEThhmmEditField.Value, ...
            string(app.lotniskoDocelowe.kodicao));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LatEditFieldLabel = uilabel(app.UIFigure);
            app.LatEditFieldLabel.Text = 'Lat';
            app.LatEditField = uieditfield(app.UIFigure, 'numeric');
            app.LonEditFieldLabel = uilabel(app.UIFigure);
            app.LonEditFieldLabel.Text = 'Lon';
            app.LonEditField = uieditfield(app.UIFigure, 'numeric');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'No'; 'RNAV'; 'Lat.'; 'Lon'; 'Distance[km]'; 'Course'; 'Cruise speed'; 'Time'; 'Altitude [FL]'};
            app.UITable.RowName = {};
            app.AddpointButton = uibutton(app.UIFigure, 'push');
            app.AddpointButton.ButtonPushedFcn = createCallbackFcn(app, @AddpointButtonPushed, true);
            app.AddpointButton.Text = 'Add point';
            app.ZatwierdzDodaniePunktuButton = uibutton(app.UIFigure, 'push');
            app.ZatwierdzDodaniePunktuButton.ButtonPushedFcn = createCallbackFcn(app, @ZatwierdzDodaniePunktuButtonPushed, true);
            app.ZatwierdzDodaniePunktuButton.Text = 'Confirm';
            app.UsunPunktButton = uibutton(app.UIFigure, 'push');
            app.UsunPunktButton.ButtonPushedFcn = createCallbackFcn(app, @UsunPunktButtonPushed, true);
            app.UsunPunktButton.Text = 'Delete point';
            app.NoLabel = uilabel(app.UIFigure);
            app.NoLabel.Text = 'No';
            app.LpPunktDoUsunieciaEditField = uieditfield(app.UIFigure, 'numeric');
            app.LpPunktDoUsunieciaEditField.Value = 1;
            app.ProfilTerenuZatwierdzButton = uibutton(app.UIFigure, 'push');
            app.ProfilTerenuZatwierdzButton.ButtonPushedFcn = createCallbackFcn(app, @ProfilTerenuZatwierdzButtonPushed, true);
            app.ProfilTerenuZatwierdzButton.Text = 'Confirm';
            app.GenerateterrainprofileLabel = uilabel(app.UIFigure);
            app.GenerateterrainprofileLabel.Text = 'Generate terrain profile';
            app.CruisingspeedkmhLabel = uilabel(app.UIFigure);
            app.CruisingspeedkmhLabel.Text = 'Cruising speed [km/h]';
            app.PredkoscPrzelotowaEditField = uieditfield(app.UIFigure, 'numeric');
            app.AltitudeFLLabel = uilabel(app.UIFigure);
            app.AltitudeFLLabel.Text = 'Altitude [FL]';
            app.WysokoscPrzelotowaEditField = uieditfield(app.UIFigure, 'numeric');
            app.TrasaEditField = uieditfield(app.UIFigure, 'text');
            app.DepartureaerodromeLabel = uilabel(app.UIFigure);
            app.DepartureaerodromeLabel.Text = {'Departure'; 'aerodrome'};
            app.LotniskaStartoweListBox = uilistbox(app.UIFigure);
            app.LotniskaStartoweListBox.Items = {'Szczecin Goleniów SZZ', 'Zielona Góra Babimost IEG', 'Wrocław Strachowice WRO', 'Poznań Ławica POZ', 'Gdańsk Rębiechowo GDN', 'Bydgoszcz Szwederowo BZG', 'Łódź Lublinek JCJ ', 'Katowice Pyrzowice KTW', 'Kraków Balice KRK', 'Olsztyn Szymany SZY', 'Warszawa Modlin WMI', 'Warszawa Okęcie WAW', 'Radom Sadków RDO', 'Lublin Świdnik LUZ', 'Rzeszów Jesionka RZE'};
            app.LotniskaStartoweListBox.ValueChangedFcn = createCallbackFcn(app, @LotniskaStartoweListBoxValueChanged, true);
            app.LotniskaStartoweListBox.Value = 'Szczecin Goleniów SZZ';
            app.DepartureaerodromeLabel_2 = uilabel(app.UIFigure);
            app.DepartureaerodromeLabel_2.Text = 'Departure aerodrome';
            app.LotniskoStartoweEditField = uieditfield(app.UIFigure, 'text');
            app.DestinationaerodromeLabel = uilabel(app.UIFigure);
            app.DestinationaerodromeLabel.Text = {'Destination'; 'aerodrome'};
            app.LotniskaDoceloweListBox = uilistbox(app.UIFigure);
            app.LotniskaDoceloweListBox.Items = {'Szczecin Goleniów SZZ', 'Zielona Góra Babimost IEG', 'Wrocław Strachowice WRO', 'Poznań Ławica POZ', 'Gdańsk Rębiechowo GDN', 'Bydgoszcz Szwederowo BZG', 'Łódź Lublinek JCJ ', 'Katowice Pyrzowice KTW', 'Kraków Balice KRK', 'Olsztyn Szymany SZY', 'Warszawa Modlin WMI', 'Warszawa Okęcie WAW', 'Radom Sadków RDO', 'Lublin Świdnik LUZ', 'Rzeszów Jesionka RZE'};
            app.LotniskaDoceloweListBox.ValueChangedFcn = createCallbackFcn(app, @LotniskaDoceloweListBoxValueChanged, true);
            app.LotniskaDoceloweListBox.Value = 'Szczecin Goleniów SZZ';
            app.DestinationaerodromeLabel_2 = uilabel(app.UIFigure);
            app.DestinationaerodromeLabel_2.Text = 'Destination aerodrome';
            app.LotniskoDoceloweEditField = uieditfield(app.UIFigure, 'text');
            app.RouteButton = uibutton(app.UIFigure, 'push');
            app.RouteButton.ButtonPushedFcn = createCallbackFcn(app, @RouteButtonPushed, true);
            app.RouteButton.Text = 'Route';
            app.SummaryButton = uibutton(app.UIFigure, 'push');
            app.SummaryButton.ButtonPushedFcn = createCallbackFcn(app, @SummaryButtonPushed, true);
            app.SummaryButton.Text = 'Summary';
            app.EEThhmmEditFieldLabel = uilabel(app.UIFigure);
            app.EEThhmmEditFieldLabel.Text = 'EET hhmm';
            app.EEThhmmEditField = uieditfield(app.UIFigure, 'text');
            app.TotaldistancekmEditFieldLabel = uilabel(app.UIFigure);
            app.TotaldistancekmEditFieldLabel.Text = 'Total distance [km]';
            app.TotaldistancekmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_Plan_Lotu3(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end