classdef equipment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SVHFRTFVORILSCheckBox          matlab.ui.control.CheckBox
        AGBASCheckBox                  matlab.ui.control.CheckBox
        BLPVAPVSBASCheckBox            matlab.ui.control.CheckBox
        CLORANCheckBox                 matlab.ui.control.CheckBox
        DDMECheckBox                   matlab.ui.control.CheckBox
        E1FMCWaypointReortingFMCWPRCheckBox  matlab.ui.control.CheckBox
        ACARSDataLinkLabel             matlab.ui.control.Label
        E2FlightInformationservicesDFISCheckBox  matlab.ui.control.CheckBox
        E3PredepartureClearencePDCCheckBox  matlab.ui.control.CheckBox
        FADFAutomatcDirectionFinderCheckBox  matlab.ui.control.CheckBox
        SURVELLANCELabel               matlab.ui.control.Label
        EQUIPMENTLabel                 matlab.ui.control.Label
        ADSB1090MHZButtonGroup         matlab.ui.container.ButtonGroup
        B1ADSB1090MHzOutButton         matlab.ui.control.RadioButton
        B2ADSB1090MHzOutandInButton    matlab.ui.control.RadioButton
        NoADSBButton_2                 matlab.ui.control.RadioButton
        ADSBUATButtonGroup             matlab.ui.container.ButtonGroup
        U1ADSB978MHzUATOutButton       matlab.ui.control.RadioButton
        U2ADSB978MHzUATOutandInButton  matlab.ui.control.RadioButton
        NoADSBButton_3                 matlab.ui.control.RadioButton
        D1ADSCFANSCheckBox             matlab.ui.control.CheckBox
        G2ADSCATNCheckBox              matlab.ui.control.CheckBox
        ClearButton                    matlab.ui.control.Button
        ConfirmButton                  matlab.ui.control.Button
        ButtonGroup                    matlab.ui.container.ButtonGroup
        AMODEAButton                   matlab.ui.control.RadioButton
        CMODEACButton                  matlab.ui.control.RadioButton
        EMODESIDAltandSquitterButton   matlab.ui.control.RadioButton
        HModeSIDAltandEnhancedSquitterButton  matlab.ui.control.RadioButton
        LModeSIDAltSquitterandEnhancedSurvButton  matlab.ui.control.RadioButton
        PModeSAltnoIDButton            matlab.ui.control.RadioButton
        SModeSIDandAltButton           matlab.ui.control.RadioButton
        XModeSnoIDnoAltButton          matlab.ui.control.RadioButton
        IModeSIDnoAltButton            matlab.ui.control.RadioButton
        NoADSBButton                   matlab.ui.control.RadioButton
        SurvNILCheckBox                matlab.ui.control.CheckBox
        EqNILCheckBox                  matlab.ui.control.CheckBox
        EquipmentEditFieldLabel        matlab.ui.control.Label
        EquipmentEditField             matlab.ui.control.EditField
        SurveillenceEditFieldLabel     matlab.ui.control.Label
        SurveillenceEditField          matlab.ui.control.EditField
        GGNSSGlobalNavigationSatelliteSystemCheckBox  matlab.ui.control.CheckBox
        HHFRTFHighFrequencyRadioTelefonCheckBox  matlab.ui.control.CheckBox
        IInertialNavigationCheckBox    matlab.ui.control.CheckBox
        CPDCLControllerPilotDataLinkLabel  matlab.ui.control.Label
        J1ATNVDLMode2CheckBox          matlab.ui.control.CheckBox
        J2FANS1AHFDLCheckBox           matlab.ui.control.CheckBox
        J3FANS1AVDLModeACheckBox       matlab.ui.control.CheckBox
        J4FANS1AVDLMode2CheckBox       matlab.ui.control.CheckBox
        J5FANS1AINMARSATCheckBox       matlab.ui.control.CheckBox
        J6FANS1AMTSATCheckBox          matlab.ui.control.CheckBox
        J7FANS1AIRIDIUMCheckBox        matlab.ui.control.CheckBox
        KMicrowaveLandingSystemCheckBox  matlab.ui.control.CheckBox
        LInstrumentalLandingSystemCheckBox  matlab.ui.control.CheckBox
        M1ATCRTFINMARSATCheckBox       matlab.ui.control.CheckBox
        M2ATCRTFMTSTATCheckBox         matlab.ui.control.CheckBox
        M3ATCRTFIridiumCheckBox        matlab.ui.control.CheckBox
        OVORCheckBox                   matlab.ui.control.CheckBox
        RRNAVRNPCapablePBNCheckBox     matlab.ui.control.CheckBox
        TTACANCheckBox                 matlab.ui.control.CheckBox
        UUHFRadioCommCheckBox          matlab.ui.control.CheckBox
        VVHFRadioCommCheckBox          matlab.ui.control.CheckBox
        WRVSMCheckBox                  matlab.ui.control.CheckBox
        XMNPSCheckBox                  matlab.ui.control.CheckBox
        YVHF833kHzRadioChannelSpacingCheckBox  matlab.ui.control.CheckBox
        ZOtherCheckBox                 matlab.ui.control.CheckBox
        ADSBVDLM4ButtonGroup           matlab.ui.container.ButtonGroup
        V1ADSBVDLMode4OutButton        matlab.ui.control.RadioButton
        V1ADSBVDLMode4OutandInButton   matlab.ui.control.RadioButton
        NoADSBButton_4                 matlab.ui.control.RadioButton
    end

    
    properties (Access = private)

        %EQUIPMENT - eq
       eqN="";
       eqS="";
       eqA="";
       eqB="";
       eqC="";
       eqD="";
       
       eqE1="";
       eqE2="";
       eqE3="";
       
       eqF="";
       eqG="";
       eqH="";
       eqI="";
       
       eqJ1="";
       eqJ2="";
       eqJ3="";
       eqJ4="";
       eqJ5="";
       eqJ6="";
       eqJ7="";
    
       eqK="";%MLS
       eqL="";
       
       eqM1="";
       eqM2="";
       eqM3="";
       eqO="";
       eqR="";
       eqT="";
       eqU="";
       eqV="";
       eqW="";
       eqX="";
       eqY="";
       eqZ=""; %other
       %SURVEILLENCE
       survN="";
       
       survA="";
       survC="";
       survE="";
       survH="";
       survI="";
       survL="";
       survP="";
       survS="";
       survX="";
       
       survB1="";
       survB2="";
       
       survU1="";
       survU2="";
       
       survV1="";
       survV2="";
       
       survD1="";
       survG2="";
       
       CallingApp;  
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, formularz, EquipmentAndSurveillence)
            app.NoADSBButton.Value = 1;
            app.NoADSBButton_2.Value = 1;
            app.NoADSBButton_3.Value = 1;
            app.NoADSBButton_4.Value = 1;
            app.CallingApp = formularz;
            app.EquipmentEditField.Value = "N";
            app.SurveillenceEditField.Value = "N";
        end

        % Value changed function: SVHFRTFVORILSCheckBox
        function SVHFRTFVORILSCheckBoxValueChanged(app, event)
            if app.SVHFRTFVORILSCheckBox.Value == 1
                app.eqS = "S";
                app.eqO = "";
                app.eqL = "";
                app.eqV = "";
                app.VVHFRadioCommCheckBox.Value=1;
                app.LInstrumentalLandingSystemCheckBox.Value =1;
                 app.OVORCheckBox.Value=1;
            elseif  app.SVHFRTFVORILSCheckBox.Value ==0
                 app.VVHFRadioCommCheckBox.Value=0;
                app.LInstrumentalLandingSystemCheckBox.Value =0;
                 app.OVORCheckBox.Value=0;
                app.eqS = "";
            end
            
        end

        % Value changed function: VVHFRadioCommCheckBox
        function VVHFRadioCommCheckBoxValueChanged(app, event)
            if app.VVHFRadioCommCheckBox.Value == 1 && app.SVHFRTFVORILSCheckBox.Value ==0
                app.eqV = "V";   
            elseif app.VVHFRadioCommCheckBox.Value == 1 && app.SVHFRTFVORILSCheckBox.Value ==1
                 app.eqV = "";
            elseif  app.VVHFRadioCommCheckBox.Value ==0
                app.eqV = "";
            end
        end

        % Value changed function: LInstrumentalLandingSystemCheckBox
        function LInstrumentalLandingSystemCheckBoxValueChanged(app, event)
           if app.LInstrumentalLandingSystemCheckBox.Value == 1 && app.SVHFRTFVORILSCheckBox.Value ==0
                app.eqL = "L";
                
            elseif app.LInstrumentalLandingSystemCheckBox.Value == 1 && app.SVHFRTFVORILSCheckBox.Value ==1
                 app.eqL = "";
            elseif  app.LInstrumentalLandingSystemCheckBox.Value ==0
                app.eqL = "";
           end
           if app.LInstrumentalLandingSystemCheckBox.Value && app.OVORCheckBox.Value && app.VVHFRadioCommCheckBox.Value
               app.eqL ="";
               app.eqO = "";
               app.eqV = "";
               app.eqS = "S";
           end  
        end

        % Value changed function: OVORCheckBox
        function OVORCheckBoxValueChanged(app, event)
            if app.OVORCheckBox.Value == 1 && app.SVHFRTFVORILSCheckBox.Value ==0
                app.eqO = "O";
                
            elseif app.OVORCheckBox.Value == 1 && app.SVHFRTFVORILSCheckBox.Value ==1
                 app.eqV = "";
            elseif  app.OVORCheckBox.Value ==0
                app.eqV = "";
            end
            
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
        %WYPOSAŻENIE RADIOKMUNIKACYJNE I RADIONAWIGACYJNE
       app.EquipmentEditField.Value =...            %podstawienie danych do pola Equipment
           app.eqN+app.eqS+app.eqA+app.eqB+app.eqC+app.eqD+app.eqE1+...
           app.eqE2+app.eqE3+app.eqF+app.eqG+app.eqH+app.eqI+app.eqJ1+app.eqJ2+app.eqJ3+...
           app.eqJ4+app.eqJ5+app.eqJ6+app.eqJ7+app.eqK+app.eqL+app.eqM1+ app.eqM2+app.eqM3+...
           app.eqO+app.eqR+app.eqT+app.eqU+app.eqV+ app.eqW+app.eqX+app.eqY+app.eqZ; 
       if isempty(app.EquipmentEditField.Value)     %Przypadek gdy nie wprowadzono żadnych danych
           app.EquipmentEditField.Value="N";        %domyślną wartością jest "N" zyli brak sprzętu
       end
       if app.EqNILCheckBox.Value                   %Przypadek gdy oznaczone jest pole NIL
          app.EquipmentEditField.Value="N";
       end
       %SPRZĘT DOZOROWANIA PRZESTRZENI POWIETRZNEJ 
       app.SurveillenceEditField.Value =...         %podstawienie danych do pola Surveillence
           app.survN+app.survA+app.survC+app.survE+...
           app.survH+app.survI+app.survL+app.survP+app.survS+app.survX+app.survB1+...
           app.survB2+app.survU1+app.survU2+app.survV1+app.survV2+app.survD1+app.survG2;
       if isempty(app.SurveillenceEditField.Value)  %Przypadek gdy nie wprowadzono żadnych danych
           app.SurveillenceEditField.Value="N";     %domyślną wartością jest "N" zyli brak sprzętu
       end
       if app.SurvNILCheckBox.Value                 %Przypadek gdy oznaczone jest pole NIL
           app.SurveillenceEditField.Value="N";
       end
       EquipmentAndSurveillence = app.EquipmentEditField.Value+"/"+app.SurveillenceEditField.Value;
       passData(app.CallingApp,EquipmentAndSurveillence); %przesłanie danych do formularza       
        end

        % Value changed function: EqNILCheckBox
        function EqNILCheckBoxValueChanged(app, event)
            if app.EqNILCheckBox.Value == 1
                app.eqN = "N";
                app.eqS="";
       app.eqA="";
       app.eqB="";
       app.eqC="";
       app.eqD="";
       
       app.eqE1="";
       app.eqE2="";
       app.eqE3="";
       
       app.eqF="";
       app.eqG="";
       app.eqH="";
       app.eqI="";
       
       app.eqJ1="";
       app.eqJ2="";
       app.eqJ3="";
       app.eqJ4="";
       app.eqJ5="";
       app.eqJ6="";
       app.eqJ7="";
    
       app.eqK="";%MLS
       app.eqL="";
       
       app.eqM1="";
       app.eqM2="";
       app.eqM3="";
       app.eqO="";
       app.eqR="";
       app.eqT="";
       app.eqU="";
       app.eqV="";
      app.eqW="";
       app.eqX="";
       app.eqY="";
       app.eqZ="";
       app.SVHFRTFVORILSCheckBox.Value = 0;
       app.AGBASCheckBox.Value = 0;
       app.BLPVAPVSBASCheckBox.Value = 0;
       app.CLORANCheckBox.Value = 0;
       app.DDMECheckBox.Value = 0;
       app.E1FMCWaypointReortingFMCWPRCheckBox.Value = 0;
       app.E2FlightInformationservicesDFISCheckBox.Value = 0;
       app.E3PredepartureClearencePDCCheckBox.Value = 0;
       app.FADFAutomatcDirectionFinderCheckBox.Value = 0;
       app.GGNSSGlobalNavigationSatelliteSystemCheckBox.Value = 0;
       app.HHFRTFHighFrequencyRadioTelefonCheckBox.Value = 0;
       app.IInertialNavigationCheckBox.Value = 0;
       app.J1ATNVDLMode2CheckBox.Value = 0;
       app.J2FANS1AHFDLCheckBox.Value = 0;
       app.J3FANS1AVDLModeACheckBox.Value = 0;
       app.J4FANS1AVDLMode2CheckBox.Value = 0;
       app.J5FANS1AINMARSATCheckBox.Value = 0;
       app.J6FANS1AMTSATCheckBox.Value = 0;
       app.J7FANS1AIRIDIUMCheckBox.Value = 0;
       app.KMicrowaveLandingSystemCheckBox.Value = 0;
       app.LInstrumentalLandingSystemCheckBox.Value = 0;
       app.M1ATCRTFINMARSATCheckBox.Value = 0;
       app.M2ATCRTFMTSTATCheckBox.Value = 0;
       app.M3ATCRTFIridiumCheckBox.Value = 0;
       app.OVORCheckBox.Value = 0;
       app.RRNAVRNPCapablePBNCheckBox.Value = 0;
       app.TTACANCheckBox.Value = 0;
       app.UUHFRadioCommCheckBox.Value = 0;
       app.VVHFRadioCommCheckBox.Value = 0;
       app.WRVSMCheckBox.Value = 0;
       app.XMNPSCheckBox.Value = 0;
       app.YVHF833kHzRadioChannelSpacingCheckBox.Value = 0;
       app.ZOtherCheckBox.Value = 0;
       app.EquipmentEditField.Value="N";
            else
                app.eqN = "";
            end
            
        end

        % Value changed function: AGBASCheckBox
        function AGBASCheckBoxValueChanged(app, event)
           if app.AGBASCheckBox.Value
              app.eqA="A";
           else
               app.eqA ="";
           end
        end

        % Value changed function: CLORANCheckBox
        function CLORANCheckBoxValueChanged(app, event)
                       if app.CLORANCheckBox.Value
              app.eqC="C";
           else
               app.eqC ="";
                       end
            
        end

        % Value changed function: DDMECheckBox
        function DDMECheckBoxValueChanged(app, event)
                               if app.DDMECheckBox.Value
              app.eqD="D";
           else
               app.eqD ="";
                               end
            
        end

        % Value changed function: BLPVAPVSBASCheckBox
        function BLPVAPVSBASCheckBoxValueChanged(app, event)
           if app.BLPVAPVSBASCheckBox.Value
              app.eqB="B";
           else
               app.eqB ="";
           end
            
        end

        % Value changed function: 
        % E1FMCWaypointReortingFMCWPRCheckBox
        function E1FMCWaypointReortingFMCWPRCheckBoxValueChanged(app, event)
           if app.E1FMCWaypointReortingFMCWPRCheckBox.Value
              app.eqE1="E1";
           else
               app.eqE1 ="";
           end         
            
        end

        % Value changed function: 
        % E2FlightInformationservicesDFISCheckBox
        function E2FlightInformationservicesDFISCheckBoxValueChanged(app, event)
           if app.E2FlightInformationservicesDFISCheckBox.Value
              app.eqE2="E2";
           else
               app.eqE2 ="";
           end            
        end

        % Value changed function: E3PredepartureClearencePDCCheckBox
        function E3PredepartureClearencePDCCheckBoxValueChanged(app, event)
           if app.E3PredepartureClearencePDCCheckBox.Value
              app.eqE3="E3";
           else
               app.eqE3 ="";
           end      
            
        end

        % Value changed function: 
        % FADFAutomatcDirectionFinderCheckBox
        function FADFAutomatcDirectionFinderCheckBoxValueChanged(app, event)
           if app.FADFAutomatcDirectionFinderCheckBox.Value
              app.eqF="F";
           else
               app.eqF ="";
           end    
            
        end

        % Value changed function: 
        % GGNSSGlobalNavigationSatelliteSystemCheckBox
        function GGNSSGlobalNavigationSatelliteSystemCheckBoxValueChanged(app, event)
           if app.GGNSSGlobalNavigationSatelliteSystemCheckBox.Value
              app.eqG="G";
           else
               app.eqG ="";
           end    
            
        end

        % Value changed function: 
        % HHFRTFHighFrequencyRadioTelefonCheckBox
        function HHFRTFHighFrequencyRadioTelefonCheckBoxValueChanged(app, event)
           if app.HHFRTFHighFrequencyRadioTelefonCheckBox.Value
              app.eqH="H";
           else
               app.eqH ="";
           end   
        end

        % Value changed function: IInertialNavigationCheckBox
        function IInertialNavigationCheckBoxValueChanged(app, event)
           if app.IInertialNavigationCheckBox.Value
              app.eqI="I";
           else
               app.eqI ="";
           end   
            
        end

        % Value changed function: J1ATNVDLMode2CheckBox
        function J1ATNVDLMode2CheckBoxValueChanged(app, event)
           if app.J1ATNVDLMode2CheckBox.Value
              app.eqJ1="J1";
           else
               app.eqJ1 ="";
           end   
            
        end

        % Value changed function: J2FANS1AHFDLCheckBox
        function J2FANS1AHFDLCheckBoxValueChanged(app, event)
           if app.J2FANS1AHFDLCheckBox.Value
              app.eqJ2="J2";
           else
               app.eqJ2 ="";
           end   
            
        end

        % Value changed function: J3FANS1AVDLModeACheckBox
        function J3FANS1AVDLModeACheckBoxValueChanged(app, event)
           if app.J3FANS1AVDLModeACheckBox.Value
              app.eqJ3="J3";
           else
               app.eqJ3 ="";
           end   
            
        end

        % Value changed function: J4FANS1AVDLMode2CheckBox
        function J4FANS1AVDLMode2CheckBoxValueChanged(app, event)
           if app.J4FANS1AVDLMode2CheckBox.Value
              app.eqJ4="J4";
           else
               app.eqJ4 ="";
           end   
            
        end

        % Value changed function: J5FANS1AINMARSATCheckBox
        function J5FANS1AINMARSATCheckBoxValueChanged(app, event)
           if app.J5FANS1AINMARSATCheckBox.Value
              app.eqJ5="J5";
           else
               app.eqJ5 ="";
           end   
            
        end

        % Value changed function: J6FANS1AMTSATCheckBox
        function J6FANS1AMTSATCheckBoxValueChanged(app, event)
           if app.J6FANS1AMTSATCheckBox.Value
              app.eqJ6="J6";
           else
               app.eqJ6 ="";
           end   
            
        end

        % Value changed function: J7FANS1AIRIDIUMCheckBox
        function J7FANS1AIRIDIUMCheckBoxValueChanged(app, event)
           if app.J7FANS1AIRIDIUMCheckBox.Value
              app.eqJ7="J7";
           else
               app.eqJ7 ="";
           end   
            
        end

        % Value changed function: KMicrowaveLandingSystemCheckBox
        function KMicrowaveLandingSystemCheckBoxValueChanged(app, event)
           if app.KMicrowaveLandingSystemCheckBox.Value
              app.eqK="K";
           else
               app.eqK ="";
           end   
            
        end

        % Value changed function: M1ATCRTFINMARSATCheckBox
        function M1ATCRTFINMARSATCheckBoxValueChanged(app, event)
           if app.M1ATCRTFINMARSATCheckBox.Value
              app.eqM1="M1";
           else
               app.eqM1 ="";
           end   
            
        end

        % Value changed function: M2ATCRTFMTSTATCheckBox
        function M2ATCRTFMTSTATCheckBoxValueChanged(app, event)
           if app.M2ATCRTFMTSTATCheckBox.Value
              app.eqM2="M2";
           else
               app.eqM2 ="";
           end   
            
        end

        % Value changed function: M3ATCRTFIridiumCheckBox
        function M3ATCRTFIridiumCheckBoxValueChanged(app, event)
           if app.M3ATCRTFIridiumCheckBox.Value
              app.eqM3="M3";
           else
               app.eqM3 ="";
           end               
        end

        % Value changed function: RRNAVRNPCapablePBNCheckBox
        function RRNAVRNPCapablePBNCheckBoxValueChanged(app, event)
           if app.RRNAVRNPCapablePBNCheckBox.Value
              app.eqR="R";
           else
               app.eqR ="";
           end
            
        end

        % Value changed function: TTACANCheckBox
        function TTACANCheckBoxValueChanged(app, event)
           if app.TTACANCheckBox.Value
              app.eqT="T";
           else
               app.eqT="";
           end           
        end

        % Value changed function: UUHFRadioCommCheckBox
        function UUHFRadioCommCheckBoxValueChanged(app, event)
           if app.UUHFRadioCommCheckBox.Value
              app.eqU="U";
           else
               app.eqU ="";
           end
            
        end

        % Value changed function: WRVSMCheckBox
        function WRVSMCheckBoxValueChanged(app, event)
           if app.WRVSMCheckBox.Value
              app.eqW="W";
           else
               app.eqW ="";
           end
            
        end

        % Value changed function: XMNPSCheckBox
        function XMNPSCheckBoxValueChanged(app, event)
           if app.XMNPSCheckBox.Value
              app.eqX="X";
           else
               app.eqX ="";
           end
            
        end

        % Value changed function: 
        % YVHF833kHzRadioChannelSpacingCheckBox
        function YVHF833kHzRadioChannelSpacingCheckBoxValueChanged(app, event)
           if app.YVHF833kHzRadioChannelSpacingCheckBox.Value
              app.eqY="Y";
           else
               app.eqY ="";
           end
            
        end

        % Value changed function: ZOtherCheckBox
        function ZOtherCheckBoxValueChanged(app, event)
           if app.ZOtherCheckBox.Value
              app.eqZ="Z";
           else
               app.eqZ ="";
           end
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            %selectedButton = app.ButtonGroup.SelectedObject;
       app.survA="";
       app.survC="";
       app.survE="";
       app.survH="";
       app.survI="";
       app.survL="";
       app.survP="";
       app.survS="";
       app.survX="";  
        if app.AMODEAButton.Value 
            app.survA = "A";
        elseif app.CMODEACButton.Value 
            app.survC = "C";
        elseif app.EMODESIDAltandSquitterButton.Value 
            app.survE = "E";
        elseif app.HModeSIDAltandEnhancedSquitterButton.Value 
            app.survH = "H";
        elseif app.LModeSIDAltSquitterandEnhancedSurvButton.Value 
            app.survL = "L";
        elseif app.PModeSAltnoIDButton.Value 
            app.survP = "P";
        elseif app.SModeSIDandAltButton.Value 
            app.survS = "S";
        elseif app.XModeSnoIDnoAltButton.Value 
            app.survX = "X";
        elseif app.IModeSIDnoAltButton.Value 
            app.survI = "I";             
        elseif app.NoADSBButton.Value 
       app.survA="";
       app.survC="";
       app.survE="";
       app.survH="";
       app.survI="";
       app.survL="";
       app.survP="";
       app.survS="";
       app.survX="";
        end

        end

        % Selection changed function: ADSB1090MHZButtonGroup
        function ADSB1090MHZButtonGroupSelectionChanged(app, event)
            app.survB1 = "";
            app.survB2 = "";
        if app.B1ADSB1090MHzOutButton.Value 
              app.survB1 = "B1";
        elseif app.B2ADSB1090MHzOutandInButton.Value 
             app.survB2 = "B2";
        elseif app.NoADSBButton_2.Value 
            app.survB1 = "";
            app.survB2 = "";
        end          
        end

        % Selection changed function: ADSBUATButtonGroup
        function ADSBUATButtonGroupSelectionChanged(app, event)
            app.survU1 = "";
            app.survU2 = "";
        if app.U1ADSB978MHzUATOutButton.Value 
              app.survU1 = "U1";
        elseif app.U2ADSB978MHzUATOutandInButton.Value 
             app.survU2 = "U2";
        elseif app.NoADSBButton_3.Value 
            app.survU1 = "";
            app.survU2 = "";
        end
            
        end

        % Selection changed function: ADSBVDLM4ButtonGroup
        function ADSBVDLM4ButtonGroupSelectionChanged(app, event)
            app.survV1 = "";
            app.survV2 = "";
        if app.V1ADSBVDLMode4OutButton.Value 
              app.survV1 = "V1";
        elseif app.V1ADSBVDLMode4OutandInButton.Value 
             app.survV2 = "V2";
        elseif app.NoADSBButton_4.Value 
            app.survV1 = "";
            app.survV2 = "";
        end
            
            
        end

        % Value changed function: D1ADSCFANSCheckBox
        function D1ADSCFANSCheckBoxValueChanged(app, event)
           if app.D1ADSCFANSCheckBox.Value
              app.survD1="Z";
           else
               app.survD1 ="";
           end
           if app.SurvNILCheckBox.Value
               app.survD1 ="";
           end
        end

        % Value changed function: G2ADSCATNCheckBox
        function G2ADSCATNCheckBoxValueChanged(app, event)
           if app.G2ADSCATNCheckBox.Value
              app.survG2="G2";
           else
               app.survG2 ="";
           end    
           if app.SurvNILCheckBox.Value
               app.survG2 ="";
           end 
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
               app.EquipmentEditField.Value = "N";
               app.SurveillenceEditField.Value  = "N";
        end

        % Value changed function: SurvNILCheckBox
        function SurvNILCheckBoxValueChanged(app, event)
 %{        
            if app.SurvNILCheckBox.Value
                app.survN="N";
            else 
                app.survN="";
            end
            
            app.survA="";
       app.survC="";
       app.survE="";
       app.survH="";
       app.survI="";
       app.survL="";
       app.survP="";
       app.survS="";
       app.survX="";
       app.survB1 = "";
       app.survB2 = "";
       app.survU1 = "";
       app.survU2 = ""; 
       app.survV1 = "";
       app.survV2 = "";
       app.survD1 ="";
       app.survG2 =""; 
            %}
       app.SurveillenceEditField.Value = "N";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SVHFRTFVORILSCheckBox = uicheckbox(app.UIFigure);
            app.SVHFRTFVORILSCheckBox.ValueChangedFcn = createCallbackFcn(app, @SVHFRTFVORILSCheckBoxValueChanged, true);
            app.SVHFRTFVORILSCheckBox.Text = 'S: VHF RTF/VOR/ILS';
            app.AGBASCheckBox = uicheckbox(app.UIFigure);
            app.AGBASCheckBox.ValueChangedFcn = createCallbackFcn(app, @AGBASCheckBoxValueChanged, true);
            app.AGBASCheckBox.Text = 'A: GBAS';
            app.BLPVAPVSBASCheckBox = uicheckbox(app.UIFigure);
            app.BLPVAPVSBASCheckBox.ValueChangedFcn = createCallbackFcn(app, @BLPVAPVSBASCheckBoxValueChanged, true);
            app.BLPVAPVSBASCheckBox.Text = 'B: LPV (APV/SBAS)';
            app.CLORANCheckBox = uicheckbox(app.UIFigure);
            app.CLORANCheckBox.ValueChangedFcn = createCallbackFcn(app, @CLORANCheckBoxValueChanged, true);
            app.CLORANCheckBox.Text = 'C: LORAN';
            app.DDMECheckBox = uicheckbox(app.UIFigure);
            app.DDMECheckBox.ValueChangedFcn = createCallbackFcn(app, @DDMECheckBoxValueChanged, true);
            app.DDMECheckBox.Text = 'D: DME';
            app.E1FMCWaypointReortingFMCWPRCheckBox = uicheckbox(app.UIFigure);
            app.E1FMCWaypointReortingFMCWPRCheckBox.ValueChangedFcn = createCallbackFcn(app, @E1FMCWaypointReortingFMCWPRCheckBoxValueChanged, true);
            app.E1FMCWaypointReortingFMCWPRCheckBox.Text = 'E1: FMC Waypoint Reorting (FMC WPR)';
            app.ACARSDataLinkLabel = uilabel(app.UIFigure);
            app.ACARSDataLinkLabel.Text = 'ACARS Data Link';
            app.E2FlightInformationservicesDFISCheckBox = uicheckbox(app.UIFigure);
            app.E2FlightInformationservicesDFISCheckBox.ValueChangedFcn = createCallbackFcn(app, @E2FlightInformationservicesDFISCheckBoxValueChanged, true);
            app.E2FlightInformationservicesDFISCheckBox.Text = 'E2: Flight Information services (D-FIS)';
            app.E3PredepartureClearencePDCCheckBox = uicheckbox(app.UIFigure);
            app.E3PredepartureClearencePDCCheckBox.ValueChangedFcn = createCallbackFcn(app, @E3PredepartureClearencePDCCheckBoxValueChanged, true);
            app.E3PredepartureClearencePDCCheckBox.Text = 'E3: Pre-departure Clearence (PDC)';
            app.FADFAutomatcDirectionFinderCheckBox = uicheckbox(app.UIFigure);
            app.FADFAutomatcDirectionFinderCheckBox.ValueChangedFcn = createCallbackFcn(app, @FADFAutomatcDirectionFinderCheckBoxValueChanged, true);
            app.FADFAutomatcDirectionFinderCheckBox.Text = 'F: ADF - Automatc Direction Finder';
            app.SURVELLANCELabel = uilabel(app.UIFigure);
            app.SURVELLANCELabel.Text = 'SURVELLANCE';
            app.EQUIPMENTLabel = uilabel(app.UIFigure);
            app.EQUIPMENTLabel.Text = 'EQUIPMENT';
            app.ADSB1090MHZButtonGroup = uibuttongroup(app.UIFigure);
            app.ADSB1090MHZButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ADSB1090MHZButtonGroupSelectionChanged, true);
            app.ADSB1090MHZButtonGroup.Title = 'ADS-B/1090 MHZ';
            app.B1ADSB1090MHzOutButton = uiradiobutton(app.ADSB1090MHZButtonGroup);
            app.B1ADSB1090MHzOutButton.Text = 'B1: ADS-B, 1090 MHz Out';
            app.B1ADSB1090MHzOutButton.Value = true;
            app.B2ADSB1090MHzOutandInButton = uiradiobutton(app.ADSB1090MHZButtonGroup);
            app.B2ADSB1090MHzOutandInButton.Text = 'B2: ADS-B, 1090 MHz Out and In';
            app.NoADSBButton_2 = uiradiobutton(app.ADSB1090MHZButtonGroup);
            app.NoADSBButton_2.Text = 'None';
            app.ADSBUATButtonGroup = uibuttongroup(app.UIFigure);
            app.ADSBUATButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ADSBUATButtonGroupSelectionChanged, true);
            app.ADSBUATButtonGroup.Title = 'ADS-B/UAT';
            app.U1ADSB978MHzUATOutButton = uiradiobutton(app.ADSBUATButtonGroup);
            app.U1ADSB978MHzUATOutButton.Text = 'U1: ADS-B. 978 MHz UAT Out';
            app.U1ADSB978MHzUATOutButton.Value = true;
            app.U2ADSB978MHzUATOutandInButton = uiradiobutton(app.ADSBUATButtonGroup);
            app.U2ADSB978MHzUATOutandInButton.Text = 'U2: ADS-B. 978 MHz UAT Out and In';
            app.NoADSBButton_3 = uiradiobutton(app.ADSBUATButtonGroup);
            app.NoADSBButton_3.Text = 'None';
            app.D1ADSCFANSCheckBox = uicheckbox(app.UIFigure);
            app.D1ADSCFANSCheckBox.ValueChangedFcn = createCallbackFcn(app, @D1ADSCFANSCheckBoxValueChanged, true);
            app.D1ADSCFANSCheckBox.Text = 'D1: ADS-C/FANS ';
            app.G2ADSCATNCheckBox = uicheckbox(app.UIFigure);
            app.G2ADSCATNCheckBox.ValueChangedFcn = createCallbackFcn(app, @G2ADSCATNCheckBoxValueChanged, true);
            app.G2ADSCATNCheckBox.Text = 'G2: ADS-C, ATN';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.AMODEAButton = uiradiobutton(app.ButtonGroup);
            app.AMODEAButton.Text = 'A: MODE A';
            app.AMODEAButton.Value = true;
            app.CMODEACButton = uiradiobutton(app.ButtonGroup);
            app.CMODEACButton.Text = 'C: MODE A/C';
            app.EMODESIDAltandSquitterButton = uiradiobutton(app.ButtonGroup);
            app.EMODESIDAltandSquitterButton.Text = 'E: MODE S, ID, Alt and Squitter';
            app.HModeSIDAltandEnhancedSquitterButton = uiradiobutton(app.ButtonGroup);
            app.HModeSIDAltandEnhancedSquitterButton.Text = 'H: Mode S, ID, Alt and Enhanced Squitter';
            app.LModeSIDAltSquitterandEnhancedSurvButton = uiradiobutton(app.ButtonGroup);
            app.LModeSIDAltSquitterandEnhancedSurvButton.Text = 'L: Mode S, ID, Alt, Squitter and Enhanced Surv';
            app.PModeSAltnoIDButton = uiradiobutton(app.ButtonGroup);
            app.PModeSAltnoIDButton.Text = 'P: Mode S, Alt, no ID';
            app.SModeSIDandAltButton = uiradiobutton(app.ButtonGroup);
            app.SModeSIDandAltButton.Text = 'S: Mode S, ID and Alt';
            app.XModeSnoIDnoAltButton = uiradiobutton(app.ButtonGroup);
            app.XModeSnoIDnoAltButton.Text = 'X: Mode S, no ID no Alt';
            app.IModeSIDnoAltButton = uiradiobutton(app.ButtonGroup);
            app.IModeSIDnoAltButton.Text = 'I: Mode S, ID, no Alt';
            app.NoADSBButton = uiradiobutton(app.ButtonGroup);
            app.NoADSBButton.Text = 'None';
            app.SurvNILCheckBox = uicheckbox(app.UIFigure);
            app.SurvNILCheckBox.ValueChangedFcn = createCallbackFcn(app, @SurvNILCheckBoxValueChanged, true);
            app.SurvNILCheckBox.Text = 'NIL';
            app.EqNILCheckBox = uicheckbox(app.UIFigure);
            app.EqNILCheckBox.ValueChangedFcn = createCallbackFcn(app, @EqNILCheckBoxValueChanged, true);
            app.EqNILCheckBox.Text = 'N: NIL';
            app.EquipmentEditFieldLabel = uilabel(app.UIFigure);
            app.EquipmentEditFieldLabel.Text = 'Equipment';
            app.EquipmentEditField = uieditfield(app.UIFigure, 'text');
            app.EquipmentEditField.Value = 'N';
            app.SurveillenceEditFieldLabel = uilabel(app.UIFigure);
            app.SurveillenceEditFieldLabel.Text = 'Surveillence';
            app.SurveillenceEditField = uieditfield(app.UIFigure, 'text');
            app.SurveillenceEditField.Value = 'N';
            app.GGNSSGlobalNavigationSatelliteSystemCheckBox = uicheckbox(app.UIFigure);
            app.GGNSSGlobalNavigationSatelliteSystemCheckBox.ValueChangedFcn = createCallbackFcn(app, @GGNSSGlobalNavigationSatelliteSystemCheckBoxValueChanged, true);
            app.GGNSSGlobalNavigationSatelliteSystemCheckBox.Text = 'G: GNSS - Global Navigation Satellite System';
            app.HHFRTFHighFrequencyRadioTelefonCheckBox = uicheckbox(app.UIFigure);
            app.HHFRTFHighFrequencyRadioTelefonCheckBox.ValueChangedFcn = createCallbackFcn(app, @HHFRTFHighFrequencyRadioTelefonCheckBoxValueChanged, true);
            app.HHFRTFHighFrequencyRadioTelefonCheckBox.Text = 'H: HF RTF - High Frequency Radio Telefon';
            app.IInertialNavigationCheckBox = uicheckbox(app.UIFigure);
            app.IInertialNavigationCheckBox.ValueChangedFcn = createCallbackFcn(app, @IInertialNavigationCheckBoxValueChanged, true);
            app.IInertialNavigationCheckBox.Text = 'I: Inertial Navigation';
            app.CPDCLControllerPilotDataLinkLabel = uilabel(app.UIFigure);
            app.CPDCLControllerPilotDataLinkLabel.Text = 'CPDCL - Controller Pilot Data Link';
            app.J1ATNVDLMode2CheckBox = uicheckbox(app.UIFigure);
            app.J1ATNVDLMode2CheckBox.ValueChangedFcn = createCallbackFcn(app, @J1ATNVDLMode2CheckBoxValueChanged, true);
            app.J1ATNVDLMode2CheckBox.Text = 'J1: ATN VDL Mode 2';
            app.J2FANS1AHFDLCheckBox = uicheckbox(app.UIFigure);
            app.J2FANS1AHFDLCheckBox.ValueChangedFcn = createCallbackFcn(app, @J2FANS1AHFDLCheckBoxValueChanged, true);
            app.J2FANS1AHFDLCheckBox.Text = 'J2: FANS 1/A HFDL';
            app.J3FANS1AVDLModeACheckBox = uicheckbox(app.UIFigure);
            app.J3FANS1AVDLModeACheckBox.ValueChangedFcn = createCallbackFcn(app, @J3FANS1AVDLModeACheckBoxValueChanged, true);
            app.J3FANS1AVDLModeACheckBox.Text = 'J3: FANS 1/A VDL Mode A';
            app.J4FANS1AVDLMode2CheckBox = uicheckbox(app.UIFigure);
            app.J4FANS1AVDLMode2CheckBox.ValueChangedFcn = createCallbackFcn(app, @J4FANS1AVDLMode2CheckBoxValueChanged, true);
            app.J4FANS1AVDLMode2CheckBox.Text = 'J4: FANS 1/A VDL Mode 2';
            app.J5FANS1AINMARSATCheckBox = uicheckbox(app.UIFigure);
            app.J5FANS1AINMARSATCheckBox.ValueChangedFcn = createCallbackFcn(app, @J5FANS1AINMARSATCheckBoxValueChanged, true);
            app.J5FANS1AINMARSATCheckBox.Text = 'J5: FANS 1/A INMARSAT';
            app.J6FANS1AMTSATCheckBox = uicheckbox(app.UIFigure);
            app.J6FANS1AMTSATCheckBox.ValueChangedFcn = createCallbackFcn(app, @J6FANS1AMTSATCheckBoxValueChanged, true);
            app.J6FANS1AMTSATCheckBox.Text = 'J6: FANS 1/A MTSAT';
            app.J7FANS1AIRIDIUMCheckBox = uicheckbox(app.UIFigure);
            app.J7FANS1AIRIDIUMCheckBox.ValueChangedFcn = createCallbackFcn(app, @J7FANS1AIRIDIUMCheckBoxValueChanged, true);
            app.J7FANS1AIRIDIUMCheckBox.Text = 'J7: FANS 1/A IRIDIUM';
            app.KMicrowaveLandingSystemCheckBox = uicheckbox(app.UIFigure);
            app.KMicrowaveLandingSystemCheckBox.ValueChangedFcn = createCallbackFcn(app, @KMicrowaveLandingSystemCheckBoxValueChanged, true);
            app.KMicrowaveLandingSystemCheckBox.Text = 'K: Microwave Landing System';
            app.LInstrumentalLandingSystemCheckBox = uicheckbox(app.UIFigure);
            app.LInstrumentalLandingSystemCheckBox.ValueChangedFcn = createCallbackFcn(app, @LInstrumentalLandingSystemCheckBoxValueChanged, true);
            app.LInstrumentalLandingSystemCheckBox.Text = 'L: Instrumental Landing System';
            app.M1ATCRTFINMARSATCheckBox = uicheckbox(app.UIFigure);
            app.M1ATCRTFINMARSATCheckBox.ValueChangedFcn = createCallbackFcn(app, @M1ATCRTFINMARSATCheckBoxValueChanged, true);
            app.M1ATCRTFINMARSATCheckBox.Text = 'M1: ATC RTF INMARSAT';
            app.M2ATCRTFMTSTATCheckBox = uicheckbox(app.UIFigure);
            app.M2ATCRTFMTSTATCheckBox.ValueChangedFcn = createCallbackFcn(app, @M2ATCRTFMTSTATCheckBoxValueChanged, true);
            app.M2ATCRTFMTSTATCheckBox.Text = 'M2: ATC RTF MTSTAT';
            app.M3ATCRTFIridiumCheckBox = uicheckbox(app.UIFigure);
            app.M3ATCRTFIridiumCheckBox.ValueChangedFcn = createCallbackFcn(app, @M3ATCRTFIridiumCheckBoxValueChanged, true);
            app.M3ATCRTFIridiumCheckBox.Text = 'M3: ATC RTF Iridium';
            app.OVORCheckBox = uicheckbox(app.UIFigure);
            app.OVORCheckBox.ValueChangedFcn = createCallbackFcn(app, @OVORCheckBoxValueChanged, true);
            app.OVORCheckBox.Text = 'O: VOR';
            app.RRNAVRNPCapablePBNCheckBox = uicheckbox(app.UIFigure);
            app.RRNAVRNPCapablePBNCheckBox.ValueChangedFcn = createCallbackFcn(app, @RRNAVRNPCapablePBNCheckBoxValueChanged, true);
            app.RRNAVRNPCapablePBNCheckBox.Text = 'R: RNAV/RNP Capable (PBN)';
            app.TTACANCheckBox = uicheckbox(app.UIFigure);
            app.TTACANCheckBox.ValueChangedFcn = createCallbackFcn(app, @TTACANCheckBoxValueChanged, true);
            app.TTACANCheckBox.Text = 'T: TACAN';
            app.UUHFRadioCommCheckBox = uicheckbox(app.UIFigure);
            app.UUHFRadioCommCheckBox.ValueChangedFcn = createCallbackFcn(app, @UUHFRadioCommCheckBoxValueChanged, true);
            app.UUHFRadioCommCheckBox.Text = 'U: UHF Radio Comm';
            app.VVHFRadioCommCheckBox = uicheckbox(app.UIFigure);
            app.VVHFRadioCommCheckBox.ValueChangedFcn = createCallbackFcn(app, @VVHFRadioCommCheckBoxValueChanged, true);
            app.VVHFRadioCommCheckBox.Text = 'V: VHF Radio Comm';
            app.WRVSMCheckBox = uicheckbox(app.UIFigure);
            app.WRVSMCheckBox.ValueChangedFcn = createCallbackFcn(app, @WRVSMCheckBoxValueChanged, true);
            app.WRVSMCheckBox.Text = 'W: RVSM';
            app.XMNPSCheckBox = uicheckbox(app.UIFigure);
            app.XMNPSCheckBox.ValueChangedFcn = createCallbackFcn(app, @XMNPSCheckBoxValueChanged, true);
            app.XMNPSCheckBox.Text = 'X: MNPS';
            app.YVHF833kHzRadioChannelSpacingCheckBox = uicheckbox(app.UIFigure);
            app.YVHF833kHzRadioChannelSpacingCheckBox.ValueChangedFcn = createCallbackFcn(app, @YVHF833kHzRadioChannelSpacingCheckBoxValueChanged, true);
            app.YVHF833kHzRadioChannelSpacingCheckBox.Text = 'Y: VHF 8.33 kHz Radio Channel Spacing';
            app.ZOtherCheckBox = uicheckbox(app.UIFigure);
            app.ZOtherCheckBox.ValueChangedFcn = createCallbackFcn(app, @ZOtherCheckBoxValueChanged, true);
            app.ZOtherCheckBox.Text = 'Z: Other';
            app.ADSBVDLM4ButtonGroup = uibuttongroup(app.UIFigure);
            app.ADSBVDLM4ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ADSBVDLM4ButtonGroupSelectionChanged, true);
            app.ADSBVDLM4ButtonGroup.Title = 'ADS-B/VDL M4';
            app.V1ADSBVDLMode4OutButton = uiradiobutton(app.ADSBVDLM4ButtonGroup);
            app.V1ADSBVDLMode4OutButton.Text = 'V1: ADS-B, VDL Mode 4 Out';
            app.V1ADSBVDLMode4OutButton.Value = true;
            app.V1ADSBVDLMode4OutandInButton = uiradiobutton(app.ADSBVDLM4ButtonGroup);
            app.V1ADSBVDLMode4OutandInButton.Text = 'V1: ADS-B, VDL Mode 4 Out and In';
            app.NoADSBButton_4 = uiradiobutton(app.ADSBVDLM4ButtonGroup);
            app.NoADSBButton_4.Text = 'None';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = equipment(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end