classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ZatwierdButton                matlab.ui.control.Button
        StwrznowstacjradiolokacyjnLabel  matlab.ui.control.Label
        SzergeogrEditFieldLabel       matlab.ui.control.Label
        SzergeogrEditField            matlab.ui.control.NumericEditField
        NazwaEditFieldLabel           matlab.ui.control.Label
        NazwaEditField                matlab.ui.control.EditField
        DugogeogrEditFieldLabel       matlab.ui.control.Label
        DugogeogrEditField            matlab.ui.control.NumericEditField
        WysokoantenyEditFieldLabel    matlab.ui.control.Label
        WysokoantenyEditField         matlab.ui.control.NumericEditField
        UsustajradiolokacyjnLabel     matlab.ui.control.Label
        NazwaEditField_2Label         matlab.ui.control.Label
        NazwaEditField_usun           matlab.ui.control.EditField
        ZatwierdButton_usun           matlab.ui.control.Button
        PokrycieradaroweButton        matlab.ui.control.Button
        WysokonadpoziomemmorzamLabel  matlab.ui.control.Label
        WysokoscNadPozMorza           matlab.ui.control.NumericEditField
        TabelaTrasyGUI                matlab.ui.control.Table
        TrasalotuLabel                matlab.ui.control.Label
        SzergeogrEditField_2Label     matlab.ui.control.Label
        SzerGeogrEditField_trasa      matlab.ui.control.NumericEditField
        DuggeogrEditFieldLabel        matlab.ui.control.Label
        DlugGeogrEditField_trasa      matlab.ui.control.NumericEditField
        WysokowzgldemterenumLabel     matlab.ui.control.Label
        WysokoscNadTerenemEditField_trasa  matlab.ui.control.NumericEditField
        ZatwierdButton_dodanie_pkt_na_trasie  matlab.ui.control.Button
        UsuButton                     matlab.ui.control.Button
        LpEditFieldLabel              matlab.ui.control.Label
        LpEditField                   matlab.ui.control.NumericEditField
        DodajpunktnatrasieLabel       matlab.ui.control.Label
        AplikacjawspomagajcaplanowaniemisjiLabel  matlab.ui.control.Label
    end

    properties (Access = public)
            nadajniki_rlok=[];
            odbiorniki_rlok=[];
            viewer = siteviewer();
            %wyznaczanie trasy
            trasa_tablica=[];   %tablica wykorzystna zostanie do zoobrazowania trasy na mapie
            trasa_tabelaGUI = []; %tabela to obiekt GUI
            liczbaPorzadkowaPktNaTrasie=0;
            dtedtfile = "n39_w106_3arc_v2.dt1";
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ZatwierdButton
        function ZatwierdButtonPushed(app, event)
            nadajnik = txsite("Name",app.NazwaEditField.Value, ...
            "AntennaHeight",app.WysokoantenyEditField.Value, ...
            "Latitude",app.SzergeogrEditField.Value, ...
            "Longitude",app.DugogeogrEditField.Value);

            odbiornik = rxsite("Name",app.NazwaEditField.Value, ...
            "AntennaHeight",app.WysokoantenyEditField.Value, ...
            "Latitude",app.SzergeogrEditField.Value, ...
            "Longitude",app.DugogeogrEditField.Value);
        
             app.nadajniki_rlok = [app.nadajniki_rlok nadajnik];
             app.odbiorniki_rlok = [app.odbiorniki_rlok odbiornik];
             show(app.nadajniki_rlok);
        end

        % Button pushed function: ZatwierdButton_usun
        function ZatwierdButton_usunPushed(app, event)
            for i=1:1:length(app.nadajniki_rlok)
                if app.NazwaEditField_usun.Value == app.nadajniki_rlok(i).Name
                    hide(app.nadajniki_rlok(i));
                    app.nadajniki_rlok(:,i) = [];
                    app.odbiorniki_rlok(:,i) = [];
                    break
                end 
            end            
        end

        % Button pushed function: PokrycieradaroweButton
        function PokrycieradaroweButtonPushed(app, event)
pd = 0.5;         % Prawdopodobieństwo detekcji
pfa = 1e-6;       % Prawdopodobieństwo fałszywego alarmu
maxrange = 35000; % Maksymalny zasięg jednoznacznego pomiaru (m)
rangeres = 5;     % Rozróżnialność (m)
tgtrcs = .1;      % Powierzchnia skuteczna celu (m^2)
numpulses = 100;  % Ilość impulsów wsygnale sondującym 

% Minimalny stosunek sygnał/szym niezbędny do wykrycia statku powietrznego [dB]
snrthreshold = albersheim(pd, pfa, numpulses);

fc = 10e9;    % Częstotliwość pracy radaru: 10 GHz
antgain = 38; % Zysk antenowy 38 dB
c = physconst('LightSpeed'); 
lambda = c/fc; %długość fali

pulsebw = c/(2*rangeres);
pulsewidth = 1/pulsebw;
Ptx = radareqpow(lambda,maxrange,snrthreshold,pulsewidth,...
                 'RCS',tgtrcs,'Gain',antgain);

% Zdefiniowanie obszaru 
latlims = [39.5 40];
lonlims = [-105.6 -105.1];
[map,~] = dted(app.dtedtfile,10,latlims,lonlims);

% Zdefiniownie siatki kwadratów
tgtlatv = linspace(latlims(1),latlims(2),length(map));
tgtlonv = linspace(lonlims(1),lonlims(2),length(map));
[tgtlons,tgtlats] = meshgrid(tgtlonv,tgtlatv);
tgtlons = tgtlons(:);
tgtlats = tgtlats(:);
map = map(:);

tgtalt = app.WysokoscNadPozMorza.Value; % Wysokość przelotowa dla której generowane jest pokrycie radarowe
%---------
app.viewer.Name = num2str(app.WysokoscNadPozMorza.Value) + "metrów nad poziomem morza";
clearMap(app.viewer) % wyczyszczenie mapy 
% Przyjęty model propagacji fali w terenie
pm = propagationModel('longley-rice');

% Obliczenie strat w wynikupropagacji sygnału w terenie
% i obliczenie odlegości pomiędzy każdym z punktów i radarem 
[L, ds] = helperPathlossOverTerrainSeaLevel(pm, app.nadajniki_rlok,...
                       app.odbiorniki_rlok, tgtlats, tgtlons, tgtalt, map);

% Oblicz SNR dla kazdego radaru i każdego punktu w terenie
numtgts = numel(tgtlats);
numrdrs = numel(app.nadajniki_rlok);
rawsnr = zeros(numtgts,numrdrs);
for tgtind = 1:numtgts
    for rdrind = 1:numrdrs
        rawsnr(tgtind,rdrind) = radareqsnr(lambda,ds(tgtind,rdrind),Ptx,pulsewidth, ...
                                    'Gain',antgain,'RCS',tgtrcs,'Loss',L(tgtind,rdrind));
    end
end

snr = max(rawsnr(:,:),[],2);

% Wyświetl położenie radarów
show(app.nadajniki_rlok);
if isempty(app.trasa_tablica)
else
 show(app.trasa_tablica);   
end

snr_elewacja=snr;
for i=1:1:length(snr)
    if map(i,1)>tgtalt
        snr_elewacja(i,1)=10000;
    end
end
strefaBezpieczna = -1000;
  for i=1:1:length(snr)
    if snr_elewacja(i,1)<snrthreshold
        snr_elewacja(i,1)=strefaBezpieczna;
    end
  end  
% Wyświetlenie stref zagrożenia dla statku powietrznego
rdrData = propagationData(tgtlats,tgtlons,"SNR",snr_elewacja);
contour(rdrData,...
   "Levels",[  snrthreshold  snrthreshold+100 ], ...
   "Colors",[ "red" "yellow"]);
        end

        % Button pushed function: 
        % ZatwierdButton_dodanie_pkt_na_trasie
        function ZatwierdButton_dodanie_pkt_na_trasiePushed(app, event)
            app.liczbaPorzadkowaPktNaTrasie=app.liczbaPorzadkowaPktNaTrasie + 1;
            
            nowy_punkt_na_trasie = txsite("Name",num2str(app.liczbaPorzadkowaPktNaTrasie), ...
                     "AntennaHeight",app.WysokoscNadTerenemEditField_trasa.Value, ...
                     "Latitude",app.SzerGeogrEditField_trasa.Value, ...
                     "Longitude",app.DlugGeogrEditField_trasa.Value);
                 
            app.trasa_tablica = [app.trasa_tablica nowy_punkt_na_trasie];
            % 3 linijki wyswietlajace dane w tabeli GUI
            nowy_wiersz_tabeli = [
                str2double(nowy_punkt_na_trasie.Name)...
                nowy_punkt_na_trasie.Latitude...
                nowy_punkt_na_trasie.Longitude...
                nowy_punkt_na_trasie.AntennaHeight];
            app.trasa_tabelaGUI = [app.trasa_tabelaGUI; nowy_wiersz_tabeli]; 
            app.TabelaTrasyGUI.Data = app.trasa_tabelaGUI;
            show(nowy_punkt_na_trasie,'Icon','samolot.png','IconSize',[12 12]);
         
            
        end

        % Button pushed function: UsuButton
        function UsuButtonPushed(app, event)
       %usuwanie pkt na trasie
       app.trasa_tabelaGUI=[];
       pkt_do_usuniecia = app.LpEditField.Value;
       hide(app.trasa_tablica(pkt_do_usuniecia));
            app.trasa_tablica(:,pkt_do_usuniecia)=[];
            for i=1:1:length(app.trasa_tablica)
                app.trasa_tablica(i).Name = num2str(i);
            end
            
            for i=1:1:length(app.trasa_tablica)
                nowy_wiersz_tabeli = [
                    str2double(app.trasa_tablica(i).Name)... 
                    app.trasa_tablica(i).Latitude...
                    app.trasa_tablica(i).Longitude... 
                    app.trasa_tablica(i).AntennaHeight];
                app.trasa_tabelaGUI=[app.trasa_tabelaGUI; nowy_wiersz_tabeli];
            end
            
        app.TabelaTrasyGUI.Data = app.trasa_tabelaGUI;
        app.liczbaPorzadkowaPktNaTrasie = length(app.trasa_tablica);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ZatwierdButton = uibutton(app.UIFigure, 'push');
            app.ZatwierdButton.ButtonPushedFcn = createCallbackFcn(app, @ZatwierdButtonPushed, true);
            app.ZatwierdButton.Text = 'Zatwierdź';
            app.StwrznowstacjradiolokacyjnLabel = uilabel(app.UIFigure);
            app.StwrznowstacjradiolokacyjnLabel.Text = 'Stwórz nową stację radiolokacyjną';
            app.SzergeogrEditFieldLabel = uilabel(app.UIFigure);
            app.SzergeogrEditFieldLabel.Text = 'Szer. geogr.';
            app.SzergeogrEditField = uieditfield(app.UIFigure, 'numeric');
            app.SzergeogrEditField.Value = 39.669462;
            app.NazwaEditFieldLabel = uilabel(app.UIFigure);
            app.NazwaEditFieldLabel.Text = 'Nazwa';
            app.NazwaEditField = uieditfield(app.UIFigure, 'text');
            app.NazwaEditField.Value = 'r1';
            app.DugogeogrEditFieldLabel = uilabel(app.UIFigure);
            app.DugogeogrEditFieldLabel.Text = 'Długość geogr.';
            app.DugogeogrEditField = uieditfield(app.UIFigure, 'numeric');
            app.DugogeogrEditField.Value = -105.219935;
            app.WysokoantenyEditFieldLabel = uilabel(app.UIFigure);
            app.WysokoantenyEditFieldLabel.Text = 'Wysokość anteny';
            app.WysokoantenyEditField = uieditfield(app.UIFigure, 'numeric');
            app.WysokoantenyEditField.Value = 10;
            app.UsustajradiolokacyjnLabel = uilabel(app.UIFigure);
            app.UsustajradiolokacyjnLabel.Text = 'Usuń staję radiolokacyjną';
            app.NazwaEditField_2Label = uilabel(app.UIFigure);
            app.NazwaEditField_2Label.Text = 'Nazwa';
            app.NazwaEditField_usun = uieditfield(app.UIFigure, 'text');
            app.ZatwierdButton_usun = uibutton(app.UIFigure, 'push');
            app.ZatwierdButton_usun.ButtonPushedFcn = createCallbackFcn(app, @ZatwierdButton_usunPushed, true);
            app.ZatwierdButton_usun.Text = 'Zatwierdź';
            app.PokrycieradaroweButton = uibutton(app.UIFigure, 'push');
            app.PokrycieradaroweButton.ButtonPushedFcn = createCallbackFcn(app, @PokrycieradaroweButtonPushed, true);
            app.PokrycieradaroweButton.Text = 'Pokrycie radarowe';
            app.WysokonadpoziomemmorzamLabel = uilabel(app.UIFigure);
            app.WysokonadpoziomemmorzamLabel.Text = {'Wysokość nad '; 'poziomem morza [m]'};
            app.WysokoscNadPozMorza = uieditfield(app.UIFigure, 'numeric');
            app.WysokoscNadPozMorza.Value = 2500;
            app.TabelaTrasyGUI = uitable(app.UIFigure);
            app.TabelaTrasyGUI.ColumnName = {'Lp.'; 'Szer. geogr.'; 'Długość geogr.'; 'Wys. nad terenem'};
            app.TabelaTrasyGUI.RowName = {};
            app.TrasalotuLabel = uilabel(app.UIFigure);
            app.TrasalotuLabel.Text = 'Trasa lotu';
            app.SzergeogrEditField_2Label = uilabel(app.UIFigure);
            app.SzergeogrEditField_2Label.Text = 'Szer. geogr';
            app.SzerGeogrEditField_trasa = uieditfield(app.UIFigure, 'numeric');
            app.SzerGeogrEditField_trasa.Value = 39.618894;
            app.DuggeogrEditFieldLabel = uilabel(app.UIFigure);
            app.DuggeogrEditFieldLabel.Text = 'Dług. geogr.';
            app.DlugGeogrEditField_trasa = uieditfield(app.UIFigure, 'numeric');
            app.DlugGeogrEditField_trasa.Value = -105.21159;
            app.WysokowzgldemterenumLabel = uilabel(app.UIFigure);
            app.WysokowzgldemterenumLabel.Text = 'Wysokość względem terenu [m]';
            app.WysokoscNadTerenemEditField_trasa = uieditfield(app.UIFigure, 'numeric');
            app.WysokoscNadTerenemEditField_trasa.Value = 10;
            app.ZatwierdButton_dodanie_pkt_na_trasie = uibutton(app.UIFigure, 'push');
            app.ZatwierdButton_dodanie_pkt_na_trasie.ButtonPushedFcn = createCallbackFcn(app, @ZatwierdButton_dodanie_pkt_na_trasiePushed, true);
            app.ZatwierdButton_dodanie_pkt_na_trasie.Text = {'Zatwierdź'; ''};
            app.UsuButton = uibutton(app.UIFigure, 'push');
            app.UsuButton.ButtonPushedFcn = createCallbackFcn(app, @UsuButtonPushed, true);
            app.UsuButton.Text = {'Usuń'; ''};
            app.LpEditFieldLabel = uilabel(app.UIFigure);
            app.LpEditFieldLabel.Text = 'Lp';
            app.LpEditField = uieditfield(app.UIFigure, 'numeric');
            app.DodajpunktnatrasieLabel = uilabel(app.UIFigure);
            app.DodajpunktnatrasieLabel.Text = 'Dodaj punkt na trasie';
            app.AplikacjawspomagajcaplanowaniemisjiLabel = uilabel(app.UIFigure);
            app.AplikacjawspomagajcaplanowaniemisjiLabel.Text = 'Aplikacja wspomagająca planowanie misji';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end