classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SzergeogrEditFieldLabel     matlab.ui.control.Label
        SzergeogrEditField          matlab.ui.control.NumericEditField
        NazwaEditFieldLabel         matlab.ui.control.Label
        NazwaEditField              matlab.ui.control.EditField
        DugogeogrEditFieldLabel     matlab.ui.control.Label
        DugogeogrEditField          matlab.ui.control.NumericEditField
        WysokoantenyEditFieldLabel  matlab.ui.control.Label
        WysokoantenyEditField       matlab.ui.control.NumericEditField
        ZatwierdButton              matlab.ui.control.Button
        StwrznowstacjrlokLabel      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ZatwierdButton
        function ZatwierdButtonPushed(app, event)
            radar = txsite("Name",app2.NazwaEditField.Value, ...
                "Latitude",app2.SzergeogrEditField.Value, ...
                "Longitude",app2.DugogeogrEditField.Value, ...
                "AntennaHeight",app2.WysokoantenyEditField.Value);
            
            app1.stacje_radiolokacyjne = [stacje_radiolokacyjne radar];
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SzergeogrEditFieldLabel = uilabel(app.UIFigure);
            app.SzergeogrEditFieldLabel.Text = 'Szer. geogr.';
            app.SzergeogrEditField = uieditfield(app.UIFigure, 'numeric');
            app.NazwaEditFieldLabel = uilabel(app.UIFigure);
            app.NazwaEditFieldLabel.Text = 'Nazwa';
            app.NazwaEditField = uieditfield(app.UIFigure, 'text');
            app.DugogeogrEditFieldLabel = uilabel(app.UIFigure);
            app.DugogeogrEditFieldLabel.Text = 'Długość geogr.';
            app.DugogeogrEditField = uieditfield(app.UIFigure, 'numeric');
            app.WysokoantenyEditFieldLabel = uilabel(app.UIFigure);
            app.WysokoantenyEditFieldLabel.Text = 'Wysokość anteny';
            app.WysokoantenyEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZatwierdButton = uibutton(app.UIFigure, 'push');
            app.ZatwierdButton.ButtonPushedFcn = createCallbackFcn(app, @ZatwierdButtonPushed, true);
            app.ZatwierdButton.Text = 'Zatwierdź';
            app.StwrznowstacjrlokLabel = uilabel(app.UIFigure);
            app.StwrznowstacjrlokLabel.Text = 'Stwórz nową stację rlok';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end